classdef BWTO2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BWTO2Dver10UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataSetsTab                     matlab.ui.container.Tab
        CloseButton                     matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        DisplayPanel                    matlab.ui.container.Panel
        PlotDataSets                    matlab.ui.control.Button
        PlotDataSetsFilter              matlab.ui.control.NumericEditField
        PlotFilterEditFieldLabel        matlab.ui.control.Label
        DataSetstoDisplay               matlab.ui.control.DropDown
        DataSetsPanel                   matlab.ui.container.Panel
        XLabel                          matlab.ui.control.Label
        PointDataButton                 matlab.ui.control.Button
        Yn                              matlab.ui.control.NumericEditField
        YnLabel                         matlab.ui.control.Label
        Xn                              matlab.ui.control.NumericEditField
        XnLabel                         matlab.ui.control.Label
        PDataLoad                       matlab.ui.control.DropDown
        CoordinatesPanel                matlab.ui.container.Panel
        AutoSpacing                     matlab.ui.control.Button
        SpacingArcSecondsEditField      matlab.ui.control.NumericEditField
        SpacingArcSecondsEditFieldLabel  matlab.ui.control.Label
        DataFilterEditField             matlab.ui.control.NumericEditField
        DataFilterEditFieldLabel        matlab.ui.control.Label
        Polygonize                      matlab.ui.control.Button
        CoordMethod                     matlab.ui.control.DropDown
        MinLatEditField                 matlab.ui.control.NumericEditField
        MinLatEditFieldLabel            matlab.ui.control.Label
        MaxLatEditField                 matlab.ui.control.NumericEditField
        MaxLatEditFieldLabel            matlab.ui.control.Label
        MaxLonEditField                 matlab.ui.control.NumericEditField
        MaxLonEditFieldLabel            matlab.ui.control.Label
        MinLonEditField                 matlab.ui.control.NumericEditField
        MinLonEditFieldLabel            matlab.ui.control.Label
        SpectralTab                     matlab.ui.container.Tab
        DisplayPanel_3                  matlab.ui.container.Panel
        PlotSpectral                    matlab.ui.control.Button
        PlotSpectralFilter              matlab.ui.control.NumericEditField
        PlotFilterEditFieldLabel_3      matlab.ui.control.Label
        SpectralDisplay                 matlab.ui.control.DropDown
        SpectralFeatureSelectionPanel   matlab.ui.container.Panel
        CWTReductionto                  matlab.ui.control.NumericEditField
        OrderXnEditFieldLabel_3         matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        FeatureSaliencyLabel            matlab.ui.control.Label
        FeatureSelectionButton          matlab.ui.control.Button
        ContinuousWaveletTransformCWTPanel  matlab.ui.container.Panel
        PoissonUPwardContinuation       matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        CWTButton                       matlab.ui.control.Button
        NumCWTFeatures                  matlab.ui.control.NumericEditField
        OrderXnEditFieldLabel_2         matlab.ui.control.Label
        N_EditField                     matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        Label_3                         matlab.ui.control.Label
        aTextArea                       matlab.ui.control.TextArea
        aTextAreaLabel                  matlab.ui.control.Label
        DerivativesofGussianPoissonWaveletsPanel  matlab.ui.container.Panel
        OrderYmEditField                matlab.ui.control.NumericEditField
        OrderYmEditFieldLabel           matlab.ui.control.Label
        OrderXnEditField                matlab.ui.control.NumericEditField
        OrderXnEditFieldLabel           matlab.ui.control.Label
        BetaSymEditField                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        NumberofAnglesLabel             matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        WSFRLabel                       matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ScaleDilationFactorLabel        matlab.ui.control.Label
        N_aEditField                    matlab.ui.control.NumericEditField
        NumberofScalesnaLabel           matlab.ui.control.Label
        MotherWaveletDropDown           matlab.ui.control.DropDown
        SpectralFeatureExtractionInputsPanel  matlab.ui.container.Panel
        SpectralInputsPointD            matlab.ui.control.CheckBox
        SpectralInputsMerge             matlab.ui.control.Button
        LineamentsTab                   matlab.ui.container.Tab
        uipanel19_2                     matlab.ui.container.Panel
        sigma_range                     matlab.ui.control.EditField
        Z_range                         matlab.ui.control.EditField
        CWTAngles__range                matlab.ui.control.EditField
        Saliency_range                  matlab.ui.control.EditField
        Dissimilarity_range             matlab.ui.control.EditField
        HystT_range                     matlab.ui.control.EditField
        StepFAngles_range               matlab.ui.control.EditField
        VSFW_range                      matlab.ui.control.EditField
        w_center                        matlab.ui.control.EditField
        CenterLabel                     matlab.ui.control.Label
        w_range                         matlab.ui.control.EditField
        RangeLabel                      matlab.ui.control.Label
        HyperparametersLabel            matlab.ui.control.Label
        FixedParametersLabel            matlab.ui.control.Label
        Yn_Auto                         matlab.ui.control.NumericEditField
        YnLabel_3                       matlab.ui.control.Label
        Xn_Auto                         matlab.ui.control.NumericEditField
        XnLabel_3                       matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        PoissonUPwardContinuation_Auto  matlab.ui.control.NumericEditField
        text128_4                       matlab.ui.control.Label
        OrderYm_Auto                    matlab.ui.control.NumericEditField
        OrderYmEditFieldLabel_2         matlab.ui.control.Label
        OrderXn_Auto                    matlab.ui.control.NumericEditField
        OrderXnEditFieldLabel_4         matlab.ui.control.Label
        Dissimilarity_Auto              matlab.ui.control.NumericEditField
        DissimilarityEditFieldLabel_2   matlab.ui.control.Label
        FeatureSaliency_Auto            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SLC_Auto                        matlab.ui.control.EditField
        text130_4                       matlab.ui.control.Label
        SpectralInputsMerge_Auto        matlab.ui.control.Button
        SpectralInputsPointD_Auto       matlab.ui.control.CheckBox
        MotherWaveletDropDown_Auto      matlab.ui.control.DropDown
        CWTReductionto_Auto             matlab.ui.control.EditField
        text130_3                       matlab.ui.control.Label
        BHOIter                         matlab.ui.control.EditField
        text131                         matlab.ui.control.Label
        Lineaments_Auto_2               matlab.ui.control.Button
        WSFR_Auto                       matlab.ui.control.EditField
        text131_6                       matlab.ui.control.Label
        NumberofAngles_Auto             matlab.ui.control.EditField
        text131_5                       matlab.ui.control.Label
        NumCWTFeatures_Auto             matlab.ui.control.EditField
        text128_3                       matlab.ui.control.Label
        ScaleDilationFactor_Auto        matlab.ui.control.EditField
        text127_3                       matlab.ui.control.Label
        NumberofScales_Auto             matlab.ui.control.EditField
        text119_3                       matlab.ui.control.Label
        LineImFilterR_Auto              matlab.ui.control.EditField
        text131_4                       matlab.ui.control.Label
        LineAng_Auto                    matlab.ui.control.EditField
        text130_2                       matlab.ui.control.Label
        LineRes_Auto                    matlab.ui.control.EditField
        text128_2                       matlab.ui.control.Label
        VSFW_Auto                       matlab.ui.control.EditField
        text127_2                       matlab.ui.control.Label
        SFW_Auto                        matlab.ui.control.EditField
        text119_2                       matlab.ui.control.Label
        DisplayPanel_4                  matlab.ui.container.Panel
        LinePlot                        matlab.ui.control.Button
        LinePlotFilter                  matlab.ui.control.NumericEditField
        PlotFilterEditFieldLabel_5      matlab.ui.control.Label
        LineDisplay                     matlab.ui.control.DropDown
        uipanel19                       matlab.ui.container.Panel
        Yn_Manual                       matlab.ui.control.NumericEditField
        YnLabel_2                       matlab.ui.control.Label
        Xn_Manual                       matlab.ui.control.NumericEditField
        XnLabel_2                       matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        SLC_Manual                      matlab.ui.control.EditField
        text130_5                       matlab.ui.control.Label
        LineSpectralInputsMerge         matlab.ui.control.Button
        LineSpectralInputsSPCA          matlab.ui.control.CheckBox
        LineSpectralInputsPointD        matlab.ui.control.CheckBox
        LineImFilterR                   matlab.ui.control.EditField
        text131_2                       matlab.ui.control.Label
        LineAng                         matlab.ui.control.EditField
        text130                         matlab.ui.control.Label
        LineRes                         matlab.ui.control.EditField
        text128                         matlab.ui.control.Label
        VSFW                            matlab.ui.control.EditField
        text127                         matlab.ui.control.Label
        Lineaments_Manual               matlab.ui.control.Button
        SFW                             matlab.ui.control.EditField
        text119                         matlab.ui.control.Label
        uipanel14                       matlab.ui.container.Panel
        text99_4                        matlab.ui.control.Label
        Beta_F                          matlab.ui.control.EditField
        text99_3                        matlab.ui.control.Label
        text98_3                        matlab.ui.control.Label
        Radius2                         matlab.ui.control.EditField
        Radius1                         matlab.ui.control.EditField
        LineTargets_Yn                  matlab.ui.control.NumericEditField
        Y_nLabel                        matlab.ui.control.Label
        LineTargets_Xn                  matlab.ui.control.NumericEditField
        X_nLabel                        matlab.ui.control.Label
        PointData_LineTarg              matlab.ui.control.Button
        ImageLoad_LineTarg              matlab.ui.control.Button
        text132                         matlab.ui.control.Label
        LineTargets_Cutoff_2            matlab.ui.control.EditField
        text117                         matlab.ui.control.Label
        LineTargets_Cutoff_1            matlab.ui.control.EditField
        text100                         matlab.ui.control.Label
        text99                          matlab.ui.control.Label
        text98                          matlab.ui.control.Label
        LineTargets_Filter              matlab.ui.control.EditField
        LineTargets_Spacing             matlab.ui.control.EditField
        ExportTab                       matlab.ui.container.Tab
        ExportButtonGroup               matlab.ui.container.ButtonGroup
        CWTAutoButton                   matlab.ui.control.RadioButton
        SelectedAutoButton              matlab.ui.control.RadioButton
        ShallowLinesAutoButton          matlab.ui.control.RadioButton
        DeepLinesAutoButton             matlab.ui.control.RadioButton
        AllLinesAutoButton              matlab.ui.control.RadioButton
        DensitiesAutoButton             matlab.ui.control.RadioButton
        ShallowLinesManualButton        matlab.ui.control.RadioButton
        DeepLinesManualButton           matlab.ui.control.RadioButton
        AllLinesManualButton            matlab.ui.control.RadioButton
        DensitiesManualButton           matlab.ui.control.RadioButton
        CWTManualButton                 matlab.ui.control.RadioButton
        ExportNum                       matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        Export_GRD_2                    matlab.ui.control.Button
        Export_CSV_2                    matlab.ui.control.Button
        PDataButton                     matlab.ui.control.RadioButton
        SelectedManualButton            matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinLonEditField
        function MinLonEditFieldValueChanged(app, event)

        end

        % Value changed function: MaxLonEditField
        function MaxLonEditFieldValueChanged(app, event)

        end

        % Value changed function: MinLatEditField
        function MinLatEditFieldValueChanged(app, event)

        end

        % Value changed function: MaxLatEditField
        function MaxLatEditFieldValueChanged(app, event)

        end

        % Value changed function: CoordMethod
        function CoordMethodValueChanged(app, event)
            value = app.CoordMethod.Value;

            disp(['Selected value: ', value]);


        end

        % Value changed function: SpacingArcSecondsEditField
        function SpacingArcSecondsEditFieldValueChanged(app, event)
            Spacing = app.SpacingArcSecondsEditField.Value;

        end

        % Value changed function: DataFilterEditField
        function DataFilterEditFieldValueChanged(app, event)

        end

        % Value changed function: PDataLoad
        function PDataLoadValueChanged(app, event)
            disp(['Interpolation Method: ', app.PDataLoad.Value]);
        end

        % Value changed function: Xn
        function XnValueChanged3(app, event)

        end

        % Value changed function: Yn
        function YnValueChanged3(app, event)

        end

        % Value changed function: DataSetstoDisplay
        function DataSetstoDisplayValueChanged(app, event)
            disp(['Selected Data to Display: ', app.DataSetstoDisplay.Value]);
        end

        % Value changed function: PlotDataSetsFilter
        function PlotDataSetsFilterValueChanged(app, event)


        end

        % Button pushed function: PlotDataSets
        function PlotDataSetsButtonPushed(app, event)
            global XI
            global YI
            global All_ImGraytr
            global All_ImRtr
            global All_ImGtr
            global All_ImBtr
            global All_Data_Matrix
            global Parameter
            global ParameterGray
            global Parameter1
            global YI_Real_Ratio

            sigma2 = app.PlotDataSetsFilter.Value;

            f1 = figure(1);

            if (strcmp(app.DataSetstoDisplay.Value,'Image'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Images');
                f1.WindowState = 'maximized';
                plt1 = ceil(Parameter^0.5);
                plt2 = plt1;

                for tttt2 = 1 : Parameter
                    subplot(plt1,plt2,tttt2),
                    plot(XI,YI)
                    RGB_Sat = cat(3,FilterB(imadjust(All_ImRtr(:,:,tttt2)),sigma2),FilterB(imadjust(All_ImGtr(:,:,tttt2)),sigma2),FilterB(imadjust(All_ImBtr(:,:,tttt2)),sigma2));
                    image(RGB_Sat,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))])
                    title(['Satellite Image ',num2str(tttt2),''])
                    box on
                    set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                    colormap jet;
                    set(gca,'YDir','normal')
                    shading interp
                    view(0,90)
                    grid off
                    daspect([YI_Real_Ratio 1 1])
                end

            elseif (strcmp(app.DataSetstoDisplay.Value,'Gray'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Gray Scale');
                f1.WindowState = 'maximized';

                Surf1(All_ImGraytr,ParameterGray,sigma2);

            elseif (strcmp(app.DataSetstoDisplay.Value,'Red'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Satellite Images: Red Bands');
                f1.WindowState = 'maximized';

                Surf1(All_ImRtr,Parameter,sigma2);


            elseif (strcmp(app.DataSetstoDisplay.Value,'Green'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Satellite Images: Green Bands');
                f1.WindowState = 'maximized';

                Surf1(All_ImGtr,Parameter,sigma2);


            elseif (strcmp(app.DataSetstoDisplay.Value,'Blue'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Satellite Images: Green Bands');
                f1.WindowState = 'maximized';

                Surf1(All_ImBtr,Parameter,sigma2);


            elseif (strcmp(app.DataSetstoDisplay.Value,'Point Data'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Point Data Sets');
                f1.WindowState = 'maximized';

                Surf1(All_Data_Matrix,1,sigma2);

            end


        end

        % Button pushed function: Polygonize
        function PolygonizeButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Coordinates Read...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            clear global All_ImRtr
            clear global All_ImGtr
            clear global All_ImBtr
            clear global All_Sat_Matrix
            clear All_ImRtr
            clear All_ImGtr
            clear All_ImBtr
            clear All_Sat_Matrix

            clear global fh3
            clear fh3
            clear global fh6
            clear fh6
            clear global PPs
            clear PPs

            clear global All_Data_Matrix
            clear All_Data_Matrix
            clearvars –global

            clear global  All_Trg_Matrix
            clear global  All_Trg_Matrix0
            clear global  CWT_Features_Columns_input
            clear global  CWT_Features_Matrix
            clear global  F1
            clear global  FSS_Predicted
            clear global  MLP_Predicted
            clear global  MLP_Predicted
            clear global  Redundant_Features_Matrix
            clear global  Selected_Features_Matrix
            clear global  Spectral_kICA_Features_Matrix
            clear global  Spectral_nICA_Features_Matrix
            clear global  Spectral_PCA_Features_Matrix
            clear global  XY_Estimated
            clear global All_Data_Matrix
            clear global All_Data_Matrix2
            clear global All_ImBtr
            clear global All_ImGtr
            clear global All_ImRtr
            clear global All_Sat_Matrix
            clear global All_Trg_Matrix
            clear global All_Trg_Matrix0
            clear global BB
            clear global curv
            clear global CWT_Features_Columns
            clear global cwtmor
            clear global cwtout
            clear global cwtout_abs
            clear global Error_2
            clear global ErrorSize2_2
            clear global F1
            clear global Fe
            clear global Fe_All
            clear global Feature
            clear global FSS_Predicted
            clear global GG
            clear global grm
            clear global ImD1tr0
            clear global ImInputtr0
            clear global kFastICA_pp_IC
            clear global kICA_Features_Matrix
            clear global mB
            clear global mG
            clear global mR
            clear global N_Fs
            clear global Neurons
            clear global nFastICA_pp_IC
            clear global nICA_Features_Matrix
            clear global nRun
            clear global Num_Redundant_Features1
            clear global Num_Selected_Features1
            clear global Num_Selected_Fs1
            clear global PCA_Features_Matrix
            clear global PCA_pp_PC
            clear global Redundant_Features_Matrix
            clear global RR
            clear global Selected_Features_Matrix
            clear global TrPercent
            clear global WL
            clear global WL2
            clear global WLFs
            clear global X_Pix
            clear global XI
            clear global XI_3D
            clear global XI_G
            clear global xv
            clear global xv_tr
            clear global XY_Estimated
            clear global Y_Pix
            clear global YI_
            clear global YI_3D
            clear global YI_G
            clear global YI_Real_Ratio
            clear global yv
            clear global yv_tr
            clear global zz_3D


            clear All_Trg_Matrix
            clear All_Trg_Matrix0
            clear CWT_Features_Columns_input
            clear CWT_Features_Matrix
            clear F1
            clear FSS_Predicted
            clear MLP_Predicted
            clear MLP_Predicted
            clear Redundant_Features_Matrix
            clear Selected_Features_Matrix
            clear Spectral_kICA_Features_Matrix
            clear Spectral_nICA_Features_Matrix
            clear Spectral_PCA_Features_Matrix
            clear XY_Estimated
            clear All_Data_Matrix
            clear All_Data_Matrix2
            clear All_ImBtr
            clear All_ImGtr
            clear All_ImRtr
            clear All_Sat_Matrix
            clear All_Trg_Matrix
            clear All_Trg_Matrix0
            clear BB
            clear curv
            clear CWT_Features_Columns
            clear cwtmor
            clear cwtout
            clear cwtout_abs
            clear Error_2
            clear ErrorSize2_2
            clear F1
            clear Fe
            clear Fe_All
            clear Feature
            clear FSS_Predicted
            clear GG
            clear grm
            clear ImD1tr0
            clear ImInputtr0
            clear kFastICA_pp_IC
            clear kICA_Features_Matrix
            clear SkICA_Features_Matrix
            clear mB
            clear mG
            clear mR
            clear N_Fs
            clear Neurons
            clear nFastICA_pp_IC
            clear nICA_Features_Matrix
            clear SnICA_Features_Matrix
            clear nRun
            clear Num_Redundant_Features1
            clear Num_Selected_Features1
            clear Num_Selected_Fs1
            clear PCA_Features_Matrix
            clear PCA_pp_PC
            clear Redundant_Features_Matrix
            clear RR
            clear Selected_Features_Matrix
            clear TrPercent
            clear WL
            clear WL2
            clear WLFs
            clear X_Pix
            clear XI
            clear XI_3D
            clear XI_G
            clear xv
            clear xv_tr
            clear XY_Estimated
            clear Y_Pix
            clear YI_
            clear YI_3D
            clear YI_G
            clear YI_Real_Ratio
            clear yv
            clear yv_tr
            clear zz_3D
            clear global All_ImGraytr


            global isin
            global YI_Real_Ratio
            global Coordinates


            if (strcmp(app.CoordMethod.Value,'Rectangular coordinates'))
                [File_Name,Path_Name] = uigetfile({'*.txt'},'File Selector');
                combin3 = join([Path_Name,File_Name]);
                Coordinates = importdata(combin3);

                app.MinLonEditField.Value = str2double(sprintf('%.15f', Coordinates(1))); % Set Onto GUI
                app.MaxLonEditField.Value = str2double(sprintf('%.15f', Coordinates(2))); % Set Onto GUI
                app.MinLatEditField.Value = str2double(sprintf('%.15f', Coordinates(3))); % Set Onto GUI
                app.MaxLatEditField.Value = str2double(sprintf('%.15f', Coordinates(4))); % Set Onto GUI


                % Set display format for high precision
                app.MinLonEditField.ValueDisplayFormat = '%.15f';
                app.MaxLonEditField.ValueDisplayFormat = '%.15f';
                app.MinLatEditField.ValueDisplayFormat = '%.15f';
                app.MaxLatEditField.ValueDisplayFormat = '%.15f';


                SPC = app.SpacingArcSecondsEditField.Value;

                global Aut
                Aut = 10*(Coordinates(2) - Coordinates(1));


                x = [Coordinates(1);Coordinates(1);Coordinates(2);Coordinates(2)];
                y = [Coordinates(3);Coordinates(4);Coordinates(3);Coordinates(4)];
                Lat_mean = mean(y(:));


                YI_Real_Ratio = abs(1/(cos(deg2rad(Lat_mean))));

                global xv
                global yv

                bound = boundary(x,y,1);
                xv = x(bound);
                yv = y(bound);


                fig1 = fig(1);
                fig1.Name  = ('Outline of data: Rectangular coordinates');
                fig1.WindowState = 'maximized';
                plot(x(bound),y(bound))
                daspect([YI_Real_Ratio 1 1])

            elseif (strcmp(app.CoordMethod.Value,'Automatic polygonization'))

% --- FINAL VERSION: Supports CSV, TIF, and XYZ for Polygonization ---

% Allow user to select a CSV, TIF, or XYZ file
[File_Name, Path_Name] = uigetfile({'*.csv;*.xyz;*.tif;*.tiff', 'Boundary Data (*.csv, *.xyz, *.tif, *.tiff)';...
                                   '*.csv;*.xyz', 'Point Files (*.csv, *.xyz)';...
                                   '*.tif;*.tiff', 'Image Files (*.tif, *.tiff)';...
                                   '*.*', 'All Files (*.*)'}, ...
                                   'Select Boundary Data File');

% Check if the user cancelled the selection
if isequal(File_Name, 0)
    disp('User canceled file selection.');
    return;
end

% Determine file type from its extension
fullFilePath = fullfile(Path_Name, File_Name);
[~, ~, ext] = fileparts(fullFilePath);
ext = lower(ext);

% Branch logic based on file type
if strcmp(ext, '.csv') || strcmp(ext, '.xyz')
    % --- LOGIC FOR CSV and Geosoft XYZ FILES ---
    try
        % --- MODIFIED LINE ---
        % We explicitly add 'FileType','text' to handle the .xyz extension.
        Data0 = readmatrix(fullFilePath, 'FileType', 'text', 'CommentStyle', '/');
        
        if size(Data0, 2) < 2
            errordlg('File must contain at least two columns for X and Y coordinates.', 'Invalid File');
            return;
        end

    catch ME
        errordlg(['Failed to read file: ' ME.message], 'File Read Error');
        return;
    end
    
    x = Data0(:,1);
    y = Data0(:,2);
    Lat_mean = mean(y(:));
    YI_Real_Ratio = abs(1/(cos(deg2rad(Lat_mean))));
    Xmin1 = min(Data0(:,1));
    Xmax1 = max(Data0(:,1));
    Ymin1 = min(Data0(:,2));
    Ymax1 = max(Data0(:,2));
    app.MinLonEditField.Value = str2double(sprintf('%.15f', Xmin1)); 
    app.MaxLonEditField.Value = str2double(sprintf('%.15f', Xmax1));
    app.MinLatEditField.Value = str2double(sprintf('%.15f', Ymin1));
    app.MaxLatEditField.Value = str2double(sprintf('%.15f', Ymax1));
    app.MinLonEditField.ValueDisplayFormat = '%.15f';
    app.MaxLonEditField.ValueDisplayFormat = '%.15f';
    app.MinLatEditField.ValueDisplayFormat = '%.15f';
    app.MaxLatEditField.ValueDisplayFormat = '%.15f';
    clear SPC
    SPC = app.SpacingArcSecondsEditField.Value;
    global Aut
    Aut = 10*(Xmax1 - Xmin1);
    global xv yv
    bound = boundary(x,y,1);
    xv = x(bound);
    yv = y(bound);
    fig2 = figure(2);
    clf(fig2); 
    fig2.Name  = ('Outline of data: Automatic polygonization');
    fig2.WindowState = 'maximized';
    plot(xv,yv);
    daspect([YI_Real_Ratio 1 1]);

elseif strcmp(ext, '.tif') || strcmp(ext, '.tiff')
    % --- LOGIC FOR TIF FILES (using the -99999 NoData value) ---
    try
        [imageData, R] = readgeoraster(fullFilePath);
        if isempty(R)
            errordlg('Selected TIF is not georeferenced.', 'File Error');
            return;
        end

        [cols, rows] = meshgrid(1:size(imageData,2), 1:size(imageData,1));
        [lat_grid, lon_grid] = intrinsicToGeographic(R, cols, rows);

        lon_vector = lon_grid(:);
        lat_vector = lat_grid(:);
        val_vector = imageData(:,:,1);
        val_vector = val_vector(:);

        no_data_value = -99999;
        invalid_indices = (val_vector == no_data_value) | isnan(val_vector);

        x = lon_vector(~invalid_indices);
        y = lat_vector(~invalid_indices);

        if isempty(x)
            errordlg(['No valid data points found in the TIF file after filtering the value ' num2str(no_data_value) '.'], 'Data Error');
            return;
        end

        Lat_mean = mean(y(:));
        YI_Real_Ratio = abs(1/(cos(deg2rad(Lat_mean))));
        Xmin1 = min(x);
        Xmax1 = max(x);
        Ymin1 = min(y);
        Ymax1 = max(y);
        app.MinLonEditField.Value = str2double(sprintf('%.15f', Xmin1));
        app.MaxLonEditField.Value = str2double(sprintf('%.15f', Xmax1));
        app.MinLatEditField.Value = str2double(sprintf('%.15f', Ymin1));
        app.MaxLatEditField.Value = str2double(sprintf('%.15f', Ymax1));
        app.MinLonEditField.ValueDisplayFormat = '%.15f';
        app.MaxLonEditField.ValueDisplayFormat = '%.15f';
        app.MinLatEditField.ValueDisplayFormat = '%.15f';
        app.MaxLatEditField.ValueDisplayFormat = '%.15f';
        clear SPC
        SPC = app.SpacingArcSecondsEditField.Value;
        global Aut
        Aut = 10*(Xmax1 - Xmin1);
        global xv yv
        bound = boundary(x, y, 1);
        xv = x(bound);
        yv = y(bound);
        
        fig2 = figure(2);
        clf(fig2);
        fig2.Name  = ('Outline of data: Automatic polygonization from TIF');
        fig2.WindowState = 'maximized';
        plot(xv, yv);
        daspect([YI_Real_Ratio 1 1]);

    catch ME
        errordlg(['Could not process GeoTIFF file: ' ME.message], 'TIF Processing Error');
        return;
    end

else
    errordlg(['Unsupported file type for automatic polygonization: ' ext], 'File Error');
    return;
end
            end


            % Define the target geographic grid based on user input
            Xmin = app.MinLonEditField.Value; % Minimum longitude
            Xmax = app.MaxLonEditField.Value; % Maximum longitude
            Ymin = app.MinLatEditField.Value; % Minimum latitude
            Ymax = app.MaxLatEditField.Value; % Maximum latitude

            Spacing_X = SPC;
            Spacing_X = Spacing_X / 3600;                              % Arc-Second to degree
            Spacing_Y = Spacing_X;                                     % Arc-Second to degree

            % Generate the target grid coordinates
            xx = Xmin:Spacing_X:Xmax;
            yy = Ymin:Spacing_Y:Ymax;
            global XI
            global YI
            [XI, YI] = meshgrid(xx, yy);


                min_XI = min(XI(:));
                max_XI = max(XI(:));

                min_YI = min(YI(:));
                max_YI = max(YI(:));      

            global in
            in = inpolygon(XI,YI,xv,yv);


            sie_XI = size(XI);
            sie_YI = size(YI);

            XPixels = sie_XI(1,2);
            YPixels = sie_YI(1,1);


            app.Xn.Value = XPixels;
            app.Yn.Value = YPixels;


            disp('Coordinates loaded');

            close(h)
            pause(0.01);


        end

        % Button pushed function: PointDataButton
        function PointDataButtonPushed(app, event)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Loading...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % 1. Update file dialog to include .xyz
            [File_Name, Path_Name] = uigetfile({'*.csv;*.xyz', 'Point Data Files (*.csv, *.xyz)';...
                                               '*.*', 'All Files (*.*)'},...
                                               'Select Point Data File');
            
            % 2. Check for user cancellation
            if isequal(File_Name, 0)
                disp('User canceled file selection.');
                close(h); % Close the waitbar
                return;
            end
            
            % 3. Read the data robustly using readmatrix
            fullFilePath = fullfile(Path_Name, File_Name);
            try
                % This single command works for both CSV and Geosoft XYZ files.
                % It handles different delimiters and skips the '/' header in XYZ files.
                Data1 = readmatrix(fullFilePath, 'FileType', 'text', 'CommentStyle', '/');
                
                % Add a check to ensure data has at least 3 columns (X, Y, Value)
                if size(Data1, 2) < 3
                    close(h);
                    errordlg('Point data file must contain at least 3 columns (X, Y, Value).', 'Invalid File Format');
                    return;
                end

            catch ME
                close(h); % Close the waitbar on error
                errordlg(['Failed to read file: ' ME.message], 'File Read Error');
                return;
            end

            sigma1 = app.DataFilterEditField.Value;   % Arc-Second to degree


            global XI
            global YI

            sie_XI = size(XI);
            sie_YI = size(YI);

            XPixels = sie_XI(1,2);
            YPixels = sie_YI(1,1);


            app.Xn.Value = XPixels;
            app.Yn.Value = YPixels;


            close(h)
            pause(0.1);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            x = Data1(:,1);
            y = Data1(:,2);
            D1 = Data1(:,3);


            if (strcmp(app.PDataLoad.Value,'Regular Interp'))
                ImD0 = griddata(x,y,D1,XI,YI,'nearest');

            elseif (strcmp(app.PDataLoad.Value,'Scattered Interp'))
                F = scatteredInterpolant(x,y,D1,'natural');
                ImD0 = F(XI,YI);

            end

            ImD1 = nDstrb2D(ImD0);


            ImD1tr0  = FilterB(ImD1,sigma1);

            global in

            ImD1tr = in.*ImD1tr0;
            ImD1tr(ImD1tr==0) = NaN;

            close(h)
            pause(0.01);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            global All_Data_Matrix

            All_Data_Matrix(:,:,1) = ImD1tr;

            close(h)
            pause(0.01);


        end

        % Button pushed function: RestartButton
        function RestartButtonPushed2(app, event)

            delete XY_Redundant_Features.csv
            delete XY_Selected_Features.csv
            delete Selected_Features_N.csv
            delete XY_Estimated(MLP).csv
            delete XY_Training_Inputs.csv
            delete XY_All_Inputs.csv
            delete X_Y_TrgData1.csv
            delete XY_Training_Targets.csv
            delete X_Y_TrgData01.csv
            delete XY_Estimated(FSS).csv

            close all
            delete(app.BWTO2Dver10UIFigure);
            clear all
            clear global
            clc
            BWTO2D


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)

            delete XY_Redundant_Features.csv
            delete XY_Selected_Features.csv
            delete Selected_Features_N.csv
            delete XY_Estimated(MLP).csv
            delete XY_Training_Inputs.csv
            delete XY_All_Inputs.csv
            delete X_Y_TrgData1.csv
            delete XY_Training_Targets.csv
            delete X_Y_TrgData01.csv
            delete XY_Estimated(FSS).csv

            close all
            delete(app.BWTO2Dver10UIFigure);
            clear all
            clear global
            clc


        end

        % Value changed function: SpectralInputsPointD
        function SpectralInputsPointDValueChanged(app, event)

        end

        % Button pushed function: SpectralInputsMerge
        function SpectralInputsMergeButtonPushed(app, event)
            fprintf('\n\n--- Preparing the inputs for CWT ---\n\n');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Merging Spectral Feature Extraction Inputs...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global All_Data_Matrix

            value5 = app.SpectralInputsPointD.Value;
            clearvars Input5
            clear input5

            clearvars fh1
            clearvars fh2
            clearvars fh3


            % Initialize a cell array to hold the selected image matrices
            selectedImages = {};

            if value5 == 1
                if ndims(All_Data_Matrix) == 2
                    selectedImages{end+1} = All_Data_Matrix(:,:,1); % Convert 2D to 3D
                else
                    selectedImages{end+1} = All_Data_Matrix;
                end
            end


            % Combine all selected images into one single 3D matrix
            global CWT_Input_Features_Matrix

            if ~isempty(selectedImages)
                CWT_Input_Features_Matrix = cat(3, selectedImages{:});
            else
                error('No images selected.');
            end


            N_Angles = app.N_EditField.Value;
            N_Scales = app.N_aEditField.Value;


            CWT_Input_Features_Matrix_Size1 = size(CWT_Input_Features_Matrix);
            CWT_Input_Features_Matrix_Size2 = size(CWT_Input_Features_Matrix_Size1); CWT_Input_Features_Matrix_Size2 = CWT_Input_Features_Matrix_Size2(1,2);

            if CWT_Input_Features_Matrix_Size2 == 2

                CWT_Input_Features_Matrix_Size = 1;
                WCT_F = CWT_Input_Features_Matrix_Size * N_Angles * N_Scales; % Number of input/output CWT features

            elseif CWT_Input_Features_Matrix_Size2 == 3

                CWT_Input_Features_Matrix_Size = size(CWT_Input_Features_Matrix);
                CWT_Input_Features_Matrix_Size4 = CWT_Input_Features_Matrix_Size(1,3);
                WCT_F = CWT_Input_Features_Matrix_Size4 * N_Angles * N_Scales; % Number of input/output CWT features

            end

            app.NumCWTFeatures.Value = WCT_F;

            scale_dilation = app.EditField.Value;
            Scales =  1:scale_dilation:(N_Scales*scale_dilation);

            ScalesStr = mat2str(Scales);
            ScalesStr = strrep(ScalesStr, '[', ''); % Remove opening bracket
            ScalesStr = strrep(ScalesStr, ']', ''); % Remove closing bracket
            ScalesStr = regexprep(num2str(ScalesStr), '\s+', ', ');

            app.aTextArea.Value = ScalesStr; % Onto GUI


            global betad

            global orderx
            global ordery

            orderx = app.OrderXnEditField.Value;
            ordery = app.OrderYmEditField.Value;

            if orderx == ordery
                betad = 90;
            else
                betad = 180;
            end

            app.BetaSymEditField.Value = betad; % Onto GUI

            betad = app.BetaSymEditField.Value;
            Angless = betad/(N_Angles) - betad/(N_Angles)  :betad/(N_Angles) : betad - betad/(N_Angles);

            AnglesStr = mat2str(Angless);
            AnglesStr = strrep(AnglesStr, '[', ''); % Remove opening bracket
            AnglesStr = strrep(AnglesStr, ']', ''); % Remove closing bracket
            AnglesStr = regexprep(num2str(AnglesStr), '\s+', ', ');

            app.TextArea.Value = AnglesStr; % Onto GUI

            numberoffeatures2 = WCT_F/(N_Angles*N_Scales);

            disp(['Number of images: ', num2str(numberoffeatures2)]);
            disp(['Scales: ', num2str(ScalesStr)]);
            disp(['Angles: ', num2str(AnglesStr)]);
            disp(['Number of CWT Features: ', num2str(WCT_F)]);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            close(h)
            pause(0.01);


        end

        % Value changed function: MotherWaveletDropDown
        function MotherWaveletDropDownValueChanged(app, event)
            fprintf('\n\n--- Selecting Mother Wavelet ---\n\n');

            global Spectral_PCA_Features_Matrix % Output storage for 2D PCA features
            clear Spectral_PCA_Features_Matrix
            clear global Spectral_PCA_Features_Matrix


            clearvars pop_choice2

            global pop_choice2

            pop_choice2 = app.MotherWaveletDropDown.Value;


            if (strcmp(pop_choice2,'Derivatives of Gaussian (Asymmetric)'))
                pop_choice2 = cell({'gaus'}); pop_choice2  = cell2mat(pop_choice2);

            elseif (strcmp(pop_choice2,'Derivatives of Poisson (Asymmetric)'))
                pop_choice2 = cell({'derpoisson2d'}); pop_choice2  = cell2mat(pop_choice2);

            end


            disp(['Selected Mother Wavelet: ', pop_choice2]);


        end

        % Value changed function: N_aEditField
        function N_aEditFieldValueChanged(app, event)

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)

        end

        % Value changed function: BetaSymEditField
        function BetaSymEditFieldValueChanged(app, event)
            value = app.BetaSymEditField.Value;

        end

        % Button pushed function: CWTButton
        function CWTButtonPushed(app, event)
            fprintf('\n\n--- Continuous Wavelet Transform (CWT) ---\n\n');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Continuous Wavelet Transform...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global CWT_Input_Features_Matrix
            global Scales
            global Angles
            global N_Scales
            global orderx
            global ordery
            global Sigma3
            global N_Fs
            global N_Angles


            % Replace NaNs with zeros
            CWT_Input_Features_Matrix(isnan(CWT_Input_Features_Matrix)) = 0;

            Sigma3 = app.EditField_3.Value;

            Scales = app.aTextArea.Value;
            Scales = Scales{1};  % Extract the string from the cell array
            Scales = str2num(Scales);  %#ok<ST2NM>

            Z =  app.PoissonUPwardContinuation.Value;

            Angles_Degree = app.TextArea.Value;
            Angles_Degree = Angles_Degree{1};  % Extract the string from the cell array
            Angles_Degree = str2num(Angles_Degree);  %#ok<ST2NM>
            Angles =  Angles_Degree*pi / (180);

            N_Scales = app.N_aEditField.Value;
            N_Angles = app.N_EditField.Value;
            N_CWT = app.NumCWTFeatures.Value;


            global N_Fs
            N_Fs = N_CWT/ (N_Angles * N_Scales);

            global Fe_All
            global pop_choice2

            if (strcmp(pop_choice2,'gaus'))
                Fe_All = cwt2D_DerGus(CWT_Input_Features_Matrix,Scales,Angles,orderx,ordery,Sigma3); % ,Scales,Angles,orderx,ordery
            elseif (strcmp(pop_choice2,'derpoisson2d'))
                Fe_All = cwt2D_DerPoisson(CWT_Input_Features_Matrix, Scales, Angles,orderx,ordery,Sigma3,Z);
            end


            close(h)
            pause(0.01);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Get the size of the input matrix
            [XI_siz, YI_siz, nn, mm, kk] = size(Fe_All);


            % Flatten the third, fourth, and fifth dimensions into a single dimension
            clear global Result3D
            global Result3D
            Result3D = reshape(Fe_All, XI_siz, YI_siz, nn * mm , kk);
            
            disp('CWT Completed');

            close(h)
            pause(0.01)


        end

        % Value changed function: NumCWTFeatures
        function NumCWTFeaturesValueChanged(app, event)

        end

        % Value changed function: CWTReductionto
        function CWTReductiontoValueChanged(app, event)

        end

        % Value changed function: aTextArea
        function aTextAreaValueChanged(app, event)
            value = app.aTextArea.Value;

            % Ensure the value is a row vector (string)
            if ischar(value)
                try
                    % Convert string to vector
                    newVector = str2num(value); %#ok<ST2NM>
                    % Check if the conversion was successful and the result is a vector
                    if isempty(newVector) || ~isvector(newVector)
                        error('Invalid input. Please enter a valid vector.');
                    end
                    % Optionally, you can update the display or process the vector further
                    disp('Updated vector:');
                    disp(newVector);
                catch
                    errordlg('Invalid input. Please enter a valid vector.');
                end
            else
                errordlg('Invalid input. Please enter a valid vector.');
            end

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

        end

        % Value changed function: SpectralDisplay
        function SpectralDisplayValueChanged(app, event)

        end

        % Value changed function: PlotSpectralFilter
        function PlotSpectralFilterValueChanged(app, event)

        end

        % Button pushed function: PlotSpectral
        function PlotSpectralButtonPushed(app, event)
            fprintf('\n\n--- Plot Spectral Feature Extraction Results ---\n\n');
            % disp(['Plot Filter Ratio: ', PlotDataSetsFilter]);

            global Spectral_PCA_Features_Matrix
 
            sigma2 = app.PlotSpectralFilter.Value;
            global Result3D
            WCT_F = app.NumCWTFeatures.Value;
            global Angelessss
            Angelessss = app.TextArea.Value;


            f1 = figure(1);

            if (strcmp(app.SpectralDisplay.Value,'CWT'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('CWT Coefficients');
                f1.WindowState = 'maximized';
                Surf1(Result3D,WCT_F,sigma2);
                disp('CWT Coefficients');

            elseif (strcmp(app.SpectralDisplay.Value,'Selected'))

                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Selected Components (Manual)');
                f1.WindowState = 'maximized';

                if ndims(Spectral_PCA_Features_Matrix)==2
                    NOF = 1;
                elseif ndims(Spectral_PCA_Features_Matrix)==3
                    NOF = size(Spectral_PCA_Features_Matrix); NOF = NOF(1,3);
                end
                Surf1(Spectral_PCA_Features_Matrix,NOF,sigma2);
                disp('Selected Components (Manual)');
            end
        end

        % Button pushed function: FeatureSelectionButton
        function FeatureSelectionButtonPushed3(app, event)

            fprintf('\n\n--- CWT Feature Selection based on Localized Feature Saliency and Global Weighted Feature Dissimilarity---\n\n');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0, 'CWT Feature Selection...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Define global variables
            global Result3D % Stack of 2D input images (m x n x M for 1 image, m x n x M x P for P images)
            global in % Masking image (assuming it's the same for all input images)

            % Get the number of principal components to retain
            D_Reduct0 = app.CWTReductionto.Value;
            RudimentaryEdgePercentile = app.EditField_4.Value;
            MapCorrelationWeight = app.EditField_5.Value;

            % Clear previous results
            clear global Spectral_PCA_Features_Matrix
            global Spectral_PCA_Features_Matrix % Output storage for combined selected features for each image

            input_ndims = ndims(Result3D);

            if input_ndims == 2
                 % Handle single 2D input - unlikely for CWT which expects at least 3D (image x scales)
                 % This part might need re-evaluation based on how Result3D is populated for 2D.
                 % Assuming Result3D is at least 3D for meaningful CWT.
                 % SPCA_Features = Result3D; % This seems incorrect for CWT input
                 Spectral_PCA_Features_Matrix(:, :, 1) = Result3D;
                 % warning('Input is 2D. CWT typically operates on 3D data (image x scales). Please check input format.');
                 % Further processing for 2D would be different, skipping for now based on the core request.
                 close(h); % Close waitbar if we exit early
                 return; % Exit function
            elseif input_ndims == 3 % Single 3D image (m x n x scales/features)
                image_count = 1;
                % Process the single image
                SPCA_Features = CWTFeatureSelection(Result3D, D_Reduct0, RudimentaryEdgePercentile, MapCorrelationWeight);

                % Initialize the output matrix
                [rows, cols, ~] = size(SPCA_Features);
                Spectral_PCA_Features_Matrix = zeros(rows, cols, image_count);

                % Apply masking and combine selected features
                Grid = zeros(rows, cols, size(SPCA_Features, 3)); % Temporary storage for interpolated maps
                SPCA_Features(SPCA_Features == 0) = 1e-12; % Handle zero values

                for k = 1:size(SPCA_Features, 3)
                    SPCA_Featuresk = in .* SPCA_Features(:, :, k);
                    SPCA_Featuresk(SPCA_Featuresk == 0) = NaN; % Set masked values to NaN
                    % Store the interpolated result
                    Grid(:, :, k) = nDstrb2D(SPCA_Featuresk); % Assuming nDstrb2D handles NaN and interpolates
                end

                % Sum the interpolated maps
                multiplied_map = Grid(:,:,1);
                for i = 2:size(Grid, 3)
                    multiplied_map = multiplied_map + Grid(:,:,i);
                end
                % multiplied_map = nDstrb2D(double(multiplied_map)); % This final interpolation might be redundant if nDstrb2D is applied per map

                % Store the combined map for this image
                Spectral_PCA_Features_Matrix(:, :, 1) = multiplied_map;

            elseif input_ndims == 4 % Multiple 3D images (m x n x scales/features x num_images)
                image_count = size(Result3D, 4);
                [rows, cols, ~] = size(Result3D(:,:,:,1)); % Get dimensions of a single image volume

                % Initialize the output matrix to store combined maps for each image
                Spectral_PCA_Features_Matrix = zeros(rows, cols, image_count);

                for img_idx = 1:image_count
                    waitbar(img_idx / image_count, h, sprintf('Processing Image %d of %d...', img_idx, image_count));

                    % Extract the current image volume
                    current_image_volume = Result3D(:, :, :, img_idx);

                    % Apply CWT Feature Selection to the current image volume
                    % Assuming CWTFeatureSelection can handle a 3D input (m x n x scales/features)
                    SPCA_Features_current_image = CWTFeatureSelection(current_image_volume, D_Reduct0, RudimentaryEdgePercentile, MapCorrelationWeight);

                    % --- Processing for the current image (similar to the ndims==3 case) ---
                    SPCA_Features_current_image(SPCA_Features_current_image == 0) = 1e-12; % Handle zero values

                    % Initialize temporary storage for interpolated maps for this image
                    Grid_current_image = zeros(rows, cols, size(SPCA_Features_current_image, 3));

                    for k = 1:size(SPCA_Features_current_image, 3)
                        SPCA_Featuresk = in .* SPCA_Features_current_image(:, :, k);
                        SPCA_Featuresk(SPCA_Featuresk == 0) = NaN; % Set masked values to NaN
                        % Store the interpolated result
                        Grid_current_image(:, :, k) = nDstrb2D(SPCA_Featuresk); % Assuming nDstrb2D handles NaN and interpolates
                    end

                    % Sum the interpolated maps for the current image
                    multiplied_map_current_image = Grid_current_image(:,:,1);
                    for i = 2:size(Grid_current_image, 3)
                        multiplied_map_current_image = multiplied_map_current_image + Grid_current_image(:,:,i);
                    end
                    % multiplied_map_current_image = nDstrb2D(double(multiplied_map_current_image)); % Final interpolation?

                    % Store the combined map for the current image in the output matrix
                    Spectral_PCA_Features_Matrix(:, :, img_idx) = multiplied_map_current_image;
                    % --- End of processing for the current image ---
                end
            else
                 warning('Input data has unsupported number of dimensions (%d). Expected 2, 3, or 4.', input_ndims);
                 close(h); % Close waitbar if input is unsupported
                 return; % Exit function
            end

            % SPCA_Features_Size is now the number of *features* for a single image,
            % not the number of images. You might want a different variable
            % to report the number of images processed.
            % app.CWTReductionto.Value = SPCA_Features_Size; % This line should probably be removed or adjusted

            close(h);
            pause(0.01);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.66, 'Saving Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Debugging Outputs
            disp('Feature Selection Completed');
            disp(['Number of Input Images Processed: ', num2str(image_count)]);
            % You might want to report the dimensions of the output matrix
            disp(['Output Matrix Dimensions (rows x cols x images): ', num2str(size(Spectral_PCA_Features_Matrix))]);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            close(h);
            pause(0.01);

        end

        % Value changed function: LineSpectralInputsPointD
        function LineSpectralInputsPointDValueChanged(app, event)

        end

        % Value changed function: LineSpectralInputsSPCA
        function LineSpectralInputsSPCAValueChanged(app, event)

        end

        % Button pushed function: LineSpectralInputsMerge
        function LineSpectralInputsMergeButtonPushed(app, event)

            fprintf('\n\n--- Preparing the inputs for Lineaments Extraction ---\n\n');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Merging  Lineament Extraction Inputs...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global All_Data_Matrix
            global Spectral_PCA_Features_Matrix

            value5 = app.LineSpectralInputsPointD.Value;
            value9 = app.LineSpectralInputsSPCA.Value;

            clearvars Input5
            clearvars Input9

            clear input5
            clear input9

            clearvars fh1
            clearvars fh2
            clearvars fh3


            % Initialize a cell array to hold the selected image matrices
            selectedImages = {};

            % Append selected images to the cell array based on checkbox values

            if value5 == 1
                if ndims(All_Data_Matrix) == 2
                    selectedImages{end+1} = All_Data_Matrix(:,:,1); % Convert 2D to 3D
                else
                    selectedImages{end+1} = All_Data_Matrix;
                end
            end

            if value9 == 1
                if ndims(Spectral_PCA_Features_Matrix) == 2
                    selectedImages{end+1} = Spectral_PCA_Features_Matrix(:,:,1); % Convert 2D to 3D
                else
                    selectedImages{end+1} = Spectral_PCA_Features_Matrix;
                end
            end


            % Combine all selected images into one single 3D matrix


            if ~isempty(selectedImages)
                All_Inputs = cat(3, selectedImages{:});
            else
                error('No images selected.');
            end
            % All_Inputssize = size(All_Inputs)

            global Line_Input_Features_Matrix
            Line_Input_Features_Matrix = All_Inputs;

            % nanPageIndices = all(all(isnan(Line_Input_Features_Matrix), 1), 2);
            % Line_Input_Features_Matrix(:,:,nanPageIndices) = [];


            global Number_of_Features_used_for_Lineaments_Detection
            Line_Input_Features_Matrix_Size = size(Line_Input_Features_Matrix);

            if ndims(Line_Input_Features_Matrix)==2
                Number_of_Features_used_for_Lineaments_Detection = 1;
            elseif ndims(Line_Input_Features_Matrix)==3
                Number_of_Features_used_for_Lineaments_Detection = Line_Input_Features_Matrix_Size(1,3);
            end


            clearvars -global grm


            global Line_Res
            Line_Res = str2num(app.LineRes.Value);

            % Ensure Line_Res is valid
            if Line_Res <=0
                disp("Warning: Line_Res is invalid. Defaulting to 256.");
                Line_Res = 1;
            end

            global X_Pix
            global Y_Pix
            X_Pix = app.Xn.Value;
            Y_Pix = app.Yn.Value;

            global grm
            if isempty(app.SFW.Value)
                grm = (0.15 * min(X_Pix * Line_Res, Y_Pix * Line_Res));
                app.SFW.Value = num2str(grm);
                grm = double(grm);
            else
            grm = str2double(app.SFW.Value);    % grammes (w): SFW
            end


            global GSF_Angles
            GSF_Angles = str2num(app.LineAng.Value);

            global VSFW
            VSFW = str2num(app.VSFW.Value);

            app.Xn_Manual.Value = X_Pix * Line_Res;
            app.Yn_Manual.Value = Y_Pix * Line_Res;


            disp(['Number of features used for lineament extraction: ', num2str(Number_of_Features_used_for_Lineaments_Detection)]);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            close(h)
            pause(0.01);

        end

        % Value changed function: LineRes
        function LineResValueChanged(app, event)

        end

        % Value changed function: LineAng
        function LineAngValueChanged(app, event)

        end

        % Value changed function: SFW
        function SFWValueChanged(app, event)

        end

        % Value changed function: VSFW
        function VSFWValueChanged(app, event)

        end

        % Value changed function: BHOIter
        function BHOIterValueChanged(app, event)

        end

        % Button pushed function: Lineaments_Manual
        function Lineaments_ManualButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Lineaments Detection (Manual)', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global Line_Input_Features_Matrix
            global Number_of_Features_used_for_Lineaments_Detection


            sigma2 = str2num(app.LineImFilterR.Value);
            SLC = str2num(app.SLC_Manual.Value);

            Lineaments_All(Line_Input_Features_Matrix,Number_of_Features_used_for_Lineaments_Detection, sigma2, SLC);


            close(h)
            pause(0.01);

        end

        % Value changed function: LineTargets_Spacing
        function LineTargets_SpacingValueChanged(app, event)

        end

        % Value changed function: LineTargets_Filter
        function LineTargets_FilterValueChanged(app, event)

        end

        % Value changed function: LineTargets_Cutoff_1
        function LineTargets_Cutoff_1ValueChanged(app, event)

        end

        % Value changed function: LineTargets_Cutoff_2
        function LineTargets_Cutoff_2ValueChanged(app, event)

        end

        % Value changed function: LineImFilterR
        function LineImFilterRValueChanged(app, event)

        end

        % Button pushed function: ImageLoad_LineTarg
        function ImageLoad_LineTargButtonPushed(app, event)

            clear XI_
            clearvars XI_
            clear YI_
            clearvars YI_

            clear xv_tr
            clearvars xv_tr
            clear yv_tr
            clearvars yv_tr

            clear All_Trg_Matrix0
            clear All_Trg_Matrix
            clearvars All_Trg_Matrix0
            clearvars All_Trg_Matrix

            global radius2
            global radius1
            global beta

            radius1 = str2double((app.Radius1.Value));
            radius2 = str2double((app.Radius2.Value));
            beta = str2double((app.Beta_F.Value));

            % Get user-defined coordinates from the GUI
            Xmin = app.MinLonEditField.Value; % Get from GUI
            Xmax = app.MaxLonEditField.Value; % Get from GUI
            Ymin = app.MinLatEditField.Value; % Get from GUI
            Ymax = app.MaxLatEditField.Value; % Get from GUI

            % Calculate pixel spacing and number of pixels
            Spacing_X = (str2num(app.LineTargets_Spacing.Value))/3600;   % Arc-Second to degree
            Spacing_Y = Spacing_X;                                       % Arc-Second to degree

            XPixels = ceil((Xmax - Xmin)/Spacing_X);
            YPixels = ceil((Ymax - Ymin)/Spacing_Y);

            app.LineTargets_Xn.Value = XPixels;
            app.LineTargets_Yn.Value = YPixels;

            % Generate target coordinate vectors
            xx = Xmin:Spacing_X:Xmax;
            yy = Ymin:Spacing_Y:Ymax;

            global XI_ YI_
            XI_ = repmat(xx, length(yy), 1);
            YI_ = repmat(yy(:), 1, length(xx));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0,'Loading Targets Image...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Parameter1 = 1; % Image#

            warning('off','all')

            % Select the Satellite Image and load it
            [File_Name, Path_Name] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select the Satellite Image');
            if isequal(File_Name, 0)
                disp('User canceled image selection.');
                return;
            end
            SatImage = imread(fullfile(Path_Name, File_Name));

            % Derive the text file name from the image file name
            [~, name, ~] = fileparts(File_Name); % Extract name without extension
            txtFileName = [name, '.txt'];
            txtFilePath = fullfile(Path_Name, txtFileName);


            % Check if the corresponding coordinate file exists
            if isfile(txtFilePath)
                % Load the coordinate file
                Coordinates_Sat = readmatrix(txtFilePath);
                disp('Coordinate file loaded successfully.');
            else
                % Attempt to list all .txt files in the directory to help debug
                txtFiles = dir(fullfile(Path_Name, '*.txt'));
                if isempty(txtFiles)
                    disp('No text files found in the selected directory.');
                else
                    disp('The following text files are found in the directory:');
                    for k = 1:length(txtFiles)
                        disp(txtFiles(k).name);
                    end
                end
                disp('Corresponding coordinate file not found.');
                return;
            end


            Xmin_S = Coordinates_Sat(1);
            Xmax_S = Coordinates_Sat(2);
            Ymin_S = Coordinates_Sat(3);
            Ymax_S = Coordinates_Sat(4);

            XPixels_S = ceil((Xmax_S - Xmin_S)/Spacing_X);
            YPixels_S = ceil((Ymax_S - Ymin_S)/Spacing_Y);

            % Resize and process the faults image
            SatImage = imresize(SatImage,[XPixels_S YPixels_S]);

            Image_Gray = im2double(rgb2gray(SatImage));      % Gray
            Image_Gray = imcomplement(Image_Gray);
            [m,n] = size(Image_Gray);

            close(h)
            pause(0.01);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Generate target coordinate vectors
            xx_S = Xmin_S:Spacing_X:Xmax_S;
            yy_S = Ymin_S:Spacing_Y:Ymax_S;

            global XI_S YI_S
            XI_S = repmat(xx_S, length(yy_S), 1);
            YI_S = repmat(yy_S(:), 1, length(xx_S));


            global xv_tr yv_tr xv yv
            bound = boundary(XI_S(:),YI_S(:),1);
            xv_tr = XI_S(bound);
            yv_tr = YI_S(bound);

            sigma_target = str2num(app.LineTargets_Filter.Value);

            J_orig = ((XI_ - Xmin_S) * (n - 1) / (Xmax_S - Xmin_S)) + 1;
            I_orig = ((Ymax_S - YI_) * (m - 1) / (Ymax_S - Ymin_S)) + 1;

            % Interpolate using pixel-based coordinates
            Im = interp2(Image_Gray, J_orig, I_orig, 'nearest');
            Im = nDstrb2D(Im);

            ImTrgtr0  = FilterB(Im,sigma_target);

            Cuttoff1 = (app.LineTargets_Cutoff_1.Value);
            Cuttoff2 = (app.LineTargets_Cutoff_2.Value);

            if isempty(Cuttoff1)
                Cuttoff1 = vpa(min(ImTrgtr0(:)), 8); % Preserves 8 digits of precision
                Cuttoff1 = round(Cuttoff1,6);
                app.LineTargets_Cutoff_1.Value = sprintf('%.15f', Cuttoff1);
            end

            Cuttoff1 = str2double(app.LineTargets_Cutoff_1.Value);
            if Cuttoff1 ~= 0
                % str2num
                % global in1
                in1 = inpolygon(XI_,YI_,xv,yv);
                in2 = inpolygon(XI_,YI_,xv_tr,yv_tr);
                ImTrgtr1 = in1.*ImTrgtr0;
                ImTrgtr1(~in1) = NaN;

                ImTrgtr = in2.*ImTrgtr1;
                ImTrgtr(~in2) = NaN;

                size_ImTrgtr1 = size(ImTrgtr);

                if isempty(Cuttoff2)
                    for i = 1 : size_ImTrgtr1(1,1)
                        for j = 1 : size_ImTrgtr1(1,2)
                            if ImTrgtr(i,j) < Cuttoff1
                                ImTrgtr(i,j) = NaN;
                            end
                        end
                    end
                elseif ~isempty(Cuttoff2)

                    for i = 1 : size_ImTrgtr1(1,1)
                        for j = 1 : size_ImTrgtr1(1,2)
                            if ImTrgtr(i,j) < Cuttoff2
                                ImTrgtr(i,j) = NaN;
                            end
                        end
                    end
                end

            else
                in1 = inpolygon(XI_,YI_,xv,yv);
                in2 = inpolygon(XI_,YI_,xv_tr,yv_tr);
                ImTrgtr1 = in1.*ImTrgtr0;
                ImTrgtr1(~in1) = NaN;

                ImTrgtr = in2.*ImTrgtr1;
                ImTrgtr(~in2) = NaN;
            end

            clear i j

            close(h)
            pause(0.01);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global All_Trg_Matrix0 All_Trg_Matrix
            All_ImTrgtr0(:,:,Parameter1) = ImTrgtr0;
            All_Trg_Matrix0 = All_ImTrgtr0;

            All_ImTrgtr(:,:,Parameter1) = ImTrgtr;
            All_Trg_Matrix = All_ImTrgtr;

            disp('Target Lineaments Loaded');

            close(h)
            pause(0.01);


        end

        % Button pushed function: PointData_LineTarg
        function PointData_LineTargButtonPushed(app, event)

            % Get user-defined coordinates from the GUI
            Xmin = app.MinLonEditField.Value; % Get from GUI
            Xmax = app.MaxLonEditField.Value; % Get from GUI
            Ymin = app.MinLatEditField.Value; % Get from GUI
            Ymax = app.MaxLatEditField.Value; % Get from GUI

            % Calculate pixel spacing and number of pixels
            Spacing_X = (str2num(app.LineTargets_Spacing.Value))/3600;   % Arc-Second to degree
            Spacing_Y = Spacing_X;                                     % Arc-Second to degree


            XPixels = ceil((Xmax - Xmin)/Spacing_X);
            YPixels = ceil((Ymax - Ymin)/Spacing_Y);

            app.LineTargets_Xn.Value = XPixels;
            app.LineTargets_Yn.Value = YPixels;

            % Generate a grid for the coordinates
            xx = Xmin:Spacing_X:Xmax;
            yy = Ymin:Spacing_Y:Ymax;

            global XI_
            global YI_
            [XI_,YI_] = meshgrid(xx,yy);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0,'Loading Targets Point Data...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [File_Name,Path_Name] = uigetfile('*.csv');
            combin2 = join([Path_Name,File_Name]);
            Data1 = csvread(combin2);

            close(h)
            pause(0.1);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            x = Data1(:,1);
            y = Data1(:,2);
            D1 = Data1(:,3);
            D1 = nDstrb1D(D1);

            global xv_tr
            global yv_tr
            global xv
            global yv
            global radius2
            global radius1
            global beta

            radius1 = (str2double(app.Radius1.Value));
            radius2 = (str2double(app.Radius2.Value));
            beta = (str2double(app.Beta_F.Value));

            bound = boundary(x,y,1);
            xv_tr = x(bound);
            yv_tr = y(bound);

            ImD4 = griddata(x,y,D1,XI_,YI_,'natural');

            sigma_target = str2num(app.LineTargets_Filter.Value);
            ImTrgtr0  = FilterB(ImD4,sigma_target);

            in = inpolygon(XI_,YI_,xv,yv);
            in2 = inpolygon(XI_,YI_,xv_tr,yv_tr);
            ImTrgtr1 = in.*ImTrgtr0;
            ImTrgtr1(ImTrgtr1==0) = NaN;

            ImTrgtr = in2.*ImTrgtr1;
            ImTrgtr(ImTrgtr==0) = NaN;
            size_ImTrgtr1 = size(ImTrgtr);

            Cuttoff1 = min(ImTrgtr(:));

            % set(handles.edit89, 'String', Cuttoff1);
            % Cuttoff1 = (str2num(get(handles.edit89,'String')));

            app.LineTargets_Cutoff_1.Value = num2str(Cuttoff1);
            Cuttoff1 = str2num(app.LineTargets_Cutoff_1.Value);


            editString = app.LineTargets_Cutoff_2.Value;


            if isempty(editString)

                for i = 1 : size_ImTrgtr1(1,1)
                    for j = 1 : size_ImTrgtr1(1,2)
                        if ImTrgtr(i,j) <= Cuttoff1
                            ImTrgtr(i,j) = NaN;
                        end
                    end
                end


            else
                Cuttoff2 = str2num(app.LineTargets_Cutoff_2.Value);

                for i = 1 : size_ImTrgtr1(1,1)
                    for j = 1 : size_ImTrgtr1(1,2)
                        if ImTrgtr(i,j) <= Cuttoff2
                            ImTrgtr(i,j) = NaN;
                        end
                    end
                end

            end

            clear i j

            close(h)
            pause(0.01);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            global All_Trg_Matrix0
            global All_Trg_Matrix

            All_Trg_Matrix0 = ImTrgtr0;
            All_Trg_Matrix = ImTrgtr;

            close(h)
            pause(0.01);

        end

        % Value changed function: LineTargets_Xn
        function LineTargets_XnValueChanged(app, event)

        end

        % Value changed function: LineTargets_Yn
        function LineTargets_YnValueChanged(app, event)

        end

        % Button pushed function: LinePlot
        function LinePlotButtonPushed(app, event)


            % Define the figure numbers to be checked and closed
            figureNumbers = 1:50;

            % Loop through each figure number and check if it exists, then close it
            for i = figureNumbers
                if ishandle(findobj('Type', 'figure', 'Number', i))
                    close(i); % Close the figure if it exists
                end
            end

            global All_Trg_Matrix
            global YI_Real_Ratio
            global XI
            global YI

            global radius2
            global radius1
            global beta


            sigma2 = app.LinePlotFilter.Value;

            f1 = figure(1);

            if (strcmp(app.LineDisplay.Value,'Target Lines'))


                data1 = FilterB(All_Trg_Matrix,sigma2);
                data1 = imbinarize(data1);
                data1 = im2double(data1);
                data1 = cat(3, data1, data1, data1);
                data2 = imcomplement(data1);

                f1 = figure(1);
                f1.Name  = ('Digitized');
                f1.WindowState = 'maximized';

                plot(XI,YI)
                image(data2,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('Digitized Lineaments')
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                ax = gca;
                box on
                ax.TickDir = 'out';
                daspect([YI_Real_Ratio 1 1])

          
            elseif strcmp(app.LineDisplay.Value,'Rose Diagram (Target)')

                % Rose Diagram for the Target Lineaments
                data1 = FilterB(All_Trg_Matrix,sigma2);
                data1 = imbinarize(data1);
                data1 = im2double(data1);
                % data1 = cat(3, data1, data1, data1);
                data2 = imcomplement(data1);
		        [orientations360] = createRoseDiagrams(data2);

                f1 = figure(1);
                f1.Name  = ('RoseD Extracted (Manual)');
                f1.WindowState = 'maximized';

                % Create subplots for the rose diagram
                polarhistogram(deg2rad(orientations360), 36); % 36 bins for 10-degree intervals
                title('Rose Diagram: Extracted Lineaments (Target)');
                grid on


            elseif strcmp(app.LineDisplay.Value,'All Lineaments (Manual)')

                close Figure 1
                f1 = figure(1);
                f1.Name  = ('All Lineaments (Manual)');
                f1.WindowState = 'maximized';
                global Number_of_Features_used_for_Lineaments_Detection
                global plotH
                global grm2_vector
                global XI % Ensure XI and YI are globally available as they are used in image() and axis()
                global YI %
                global YI_Real_Ratio % Ensure YI_Real_Ratio is globally available for daspect()

                % Sort the step‑filtering widths in ascending order
                [~, sortedIndices] = sort(grm2_vector);

                % Create a tab group
                tg = uitabgroup(f1);

                % Loop through each feature in sorted order
                for ii = 1:Number_of_Features_used_for_Lineaments_Detection
                    idx = sortedIndices(ii);
                    t = uitab(tg,'Title',sprintf('Lineaments %d' ,ii));
                    
                    % Create axes directly in the tab
                    ax = uiaxes(t,'Units','normalized','Position',[0 0 1 1]);
                    
                    % Plot the image onto the specific axes (ax)
                    image(ax,plotH(:,:,:,idx),...
                        'XData',[min(XI(:)) max(XI(:))],...
                        'YData',[min(YI(:)) max(YI(:))]);
                    
                    ax.TickDir   = 'out';
                    ax.LineWidth = 1;
                    ax.Layer     = 'top';
                    
                    % Explicitly apply colormap to 'ax'
                    % 1) Build the exact blue→red colormap you used:
                    N      = 256;
                    reds   = linspace(0,1,N)';    % c from 0→1
                    greens = zeros(N,1);          % always zero
                    blues  = linspace(1,0,N)';    % 1–c from 1→0
                    myMap  = [reds, greens, blues];
                    colormap(ax, myMap); % <--- Changed from colormap(gca, myMap)
                    
                    % Explicitly apply caxis to 'ax'
                    caxis(ax, [0 1]); % <--- Changed from caxis([0 1])
                    
                    ax.YDir      = 'normal';
                    shading(ax,'interp');
                    view(ax,0,90);
                    grid(ax,'off');
                    ax.Box   = 'on';
                    axis(ax,'on','tight','equal'); % <--- axis also affects gca by default, better to be explicit or use properties
                    
                    title(ax,['Feature #',num2str(idx)]);
                    subtitle(ax,['w = ',num2str(grm2_vector(idx))]);
                    ax.FontSize   = 14;
                    
                    daspect(ax,[YI_Real_Ratio 1 1]);
                    
                    % Explicitly create colorbar for 'ax'
                    h_colorbar = colorbar(ax); % <--- Changed from colorbar(gca)
                    h_colorbar.Location    = 'eastoutside';
                    h_colorbar.Ticks       = [0 1];
                    h_colorbar.TickLabels  = {'Weak Faults','Strong Faults'};
                    h_colorbar.FontSize   = 12;
                end


            elseif (strcmp(app.LineDisplay.Value,'Curvilinearity Control (Manual)'))


                % Visualize step filtering widths
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Curvilinearity Control');
                f1.WindowState = 'maximized';

                global grm2_vector
                grm2_vector_s = size(grm2_vector);
                grm2_vector_s = grm2_vector_s(1,2);
                x_ax = 1:1:grm2_vector_s;
                bar(x_ax,grm2_vector,'black')
                text(x_ax, grm2_vector, num2str(grm2_vector'), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')

                title(['Average Step Filtering Width = ',num2str(mean(grm2_vector)),''])
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                curtick = get(gca, 'xTick');
                xticks(unique(round(curtick)));
                xlabel('Features')
                ylabel('Min D')
                ylim([0 (max(grm2_vector)+0.1*max(grm2_vector))])
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off


                elseif (strcmp(app.LineDisplay.Value,'Line Densities (Manual)'))

                    % Display lineament density map
                    global densityMap
                    densityMap_ = FilterB(densityMap,sigma2);


                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Line Densities (Manual)');
                f1.WindowState = 'maximized';

                    plot(XI,YI)
                    imagesc(densityMap_,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                    title('Lineaments Densities')
                    box on
                    set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                    colormap jet;
                    set(gca,'YDir','normal')
                    shading interp
                    view(0,90)
                    grid off
                    set(gcf,'GraphicsSmoothing','on')
                    daspect([YI_Real_Ratio 1 1])


            elseif (strcmp(app.LineDisplay.Value,'Lineaments (Manual)'))
               

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Lineaments (Manual)');
                f1.WindowState = 'maximized';

                global plotH1_rgb1
                plot(XI,YI)
                image(plotH1_rgb1,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('All Lineaments Stacked (Manual)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])

            elseif (strcmp(app.LineDisplay.Value,'Deep Lineaments (Manual)'))

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Deep Lineaments (Manual)');
                f1.WindowState = 'maximized';

                global plotH1_rgb1_Deep
                plot(XI,YI)
                image(plotH1_rgb1_Deep,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('Deep Lineaments Stacked (Manual)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])

            elseif (strcmp(app.LineDisplay.Value,'Shallow Lineaments (Manual)'))

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Shallow Lineaments (Manual)');
                f1.WindowState = 'maximized';

                global plotH1_rgb1_Shallow
                plot(XI,YI)
                image(plotH1_rgb1_Shallow,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('Shallow Lineaments Stacked (Manual)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])


            elseif (strcmp(app.LineDisplay.Value,'Fbeta Score (Manual)'))


                global plotH1_rgb1
                % plotH1_rgb1__Mean = mean(plotH1_rgb1(:))

                All_Trg_Matrix2 = FilterB(All_Trg_Matrix,sigma2);
                All_Trg_Matrix2 = imbinarize(All_Trg_Matrix2);
                %             All_Trg_Matrix2 = imcomplement(All_Trg_Matrix2);
                All_Trg_Matrix2 = im2double(All_Trg_Matrix2);


                plotH1_rgb_size = size(plotH1_rgb1);
                % All_Trg_Matrix2(isnan(All_Trg_Matrix2)) = 0;

                All_Trg_Matrix2 = imresize(All_Trg_Matrix2,[plotH1_rgb_size(1,1) plotH1_rgb_size(1,2)]);
                data1 = FilterB(All_Trg_Matrix2,sigma2);
                data1 = imbinarize(data1);
                data1 = im2double(data1);
                data1 = cat(3, data1, data1, data1);
                data2 = imcomplement(data1);
                plotH1_rgb2 = imcomplement(plotH1_rgb1);


                % Read the binary images
                real_faults = (data1(:,:,1)+data1(:,:,2)+data1(:,:,3))/3;
                detected_faults = (plotH1_rgb2(:,:,1)+plotH1_rgb2(:,:,2)+plotH1_rgb2(:,:,3))/3;


                % Create a disk-shaped structuring element of the given radius
                se1 = strel('rectangle', [radius1 radius1]);
                se2 = strel('rectangle', [radius2 radius2]);

                % Dilate the real faults image to create a neighborhood of radius = 5 around the fault lines
                real_faults_neighborhood1 = imdilate(real_faults, se1);
                real_faults_neighborhood2 = imdilate(real_faults, se2);


                % Apply the neighborhood to the detected faults image
                detected_faults_neighborhood = detected_faults;

                % Flatten the neighborhood images into vectors for computation
                real_faults_neighborhood1 = real_faults_neighborhood1(:);
                real_faults_neighborhood2 = real_faults_neighborhood2(:);

                detected_faults_neighborhood = detected_faults_neighborhood(:);

                % True positives
                tp = sum(real_faults_neighborhood1 & detected_faults_neighborhood);
                % fprintf('True Positives =\n\n       %d\n', tp);

                % False positives
                fp1 = sum(~real_faults_neighborhood1 & detected_faults_neighborhood);
                fp2 = sum(~real_faults_neighborhood2 & detected_faults_neighborhood);

                fp = fp1 - fp2;
                % fprintf('False Positives =\n\n       %d\n', fp);

                % False negatives
                fn = sum(real_faults_neighborhood1 & ~detected_faults_neighborhood);
                % fprintf('False  Negatives =\n\n       %d\n', fn);

                % Precision
                format bank
                precision = tp / (tp + fp);
                % Precision = precision;


                % Recall
                format bank
                recall = tp / (tp + fn);
                % Recall = recall;

                % Fbeta Score
                format bank
                f1_score = 2 * ((precision * recall) / (precision + recall));
                fbeta_score1 = (1 + beta^2) * ((precision * recall) / ((beta^2 * precision) + recall));
                Fbeta = fbeta_score1;


                f1 = figure(1);
                f1.Name = 'Overlayed Images with Simulated Transparency';
                f1.WindowState = 'maximized';

                global densityMap

                % ----- Step 1: Normalize and blend the density map ----- %
                % Normalize the density map to the range [0,1]
                densityMapNorm = mat2gray(densityMap);

                % Set a blending factor for simulated transparency (0 = fully transparent, 1 = fully opaque)
                alpha_bg = 0.8;
                % Create a white background (all ones) that matches the size of densityMapNorm
                whiteBG = ones(size(densityMapNorm));

                % Blend the normalized density map with white to simulate transparency
                blendedMap = (1 - alpha_bg) * whiteBG + alpha_bg * densityMapNorm;

                % ----- Step 2: Display the blended density map ----- %
                % Use imagesc to display the blended density map with proper spatial referencing
                imagesc([min(XI(:)) max(XI(:))], [min(YI(:)) max(YI(:))], blendedMap);
                set(gca, 'YDir', 'normal');
                colormap(gca, jet);
                hold on;

                % ----- Step 3: Prepare the digitized lineaments (data2) ----- %
                % Ensure data2 is numeric and normalize it
                data2 = double(data2);
                data2Plot = mat2gray(data2);  % Normalize for display

                % (Optional) Create a binary mask from data2 for potential pixel-wise transparency
                mask = double(data2 > 0);


                % ----- Step 4: Overlay the digitized lineaments ----- %
                % Overlay the lineaments image with the same spatial referencing
                h2 = image('CData', data2Plot, ...
                    'XData', [min(XI(:)) max(XI(:))], ...
                    'YData', [min(YI(:)) max(YI(:))]);
                set(gca, 'YDir', 'normal');

                % Use a uniform (scalar) transparency for the lineaments:
                h2.AlphaData = 0.7;  % 0.8 means 80% opaque (20% transparent)

                % ----- Step 5: Final styling ----- %
                title(['Extracted Lineaments (Manual), F-Beta Score = ', num2str(mean(fbeta_score1))]);
                colorbar;
                set(gca, 'TickDir', 'out', 'LineWidth', 1, 'Layer', 'top');
                view(0, 90);
                grid off;
                axis on;
                box on;
                daspect([YI_Real_Ratio 1 1]);

            elseif (strcmp(app.LineDisplay.Value,'Rose Diagram (Manual)'))

                % Rose Diagram
                global Orient0
                f1 = figure(1);
                f1.Name  = ('RoseD Extracted (Manual)');
                f1.WindowState = 'maximized';

                % Create subplots for the rose diagram
                polarhistogram(deg2rad(Orient0), 36); % 36 bins for 10-degree intervals
                title('Rose Diagram: Extracted Lineaments (Manual)');
                grid on


            elseif (strcmp(app.LineDisplay.Value,'CWT (Auto)'))
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('CWT Coefficients');
                f1.WindowState = 'maximized';
                global Result3D_Auto
                Result3D_Auto_Size = size(Result3D_Auto,3);
                WCT_F = app.NumCWTFeatures.Value;
                Surf1(Result3D_Auto,Result3D_Auto_Size,sigma2);
                disp('CWT Coefficients');


             elseif (strcmp(app.LineDisplay.Value,'Selected (Auto)'))

                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Selected Components (Auto)');
                f1.WindowState = 'maximized';

                global Spectral_PCA_Features_Matrix_Auto
                Grid = Spectral_PCA_Features_Matrix_Auto;
                if ndims(Grid)==2
                    NOF = 1;
                elseif ndims(Grid)==3
                    NOF = size(Grid); NOF = NOF(1,3);
                end
                Surf1(Grid,NOF,sigma2);
                disp('Selected Components (Auto)');


            elseif (strcmp(app.LineDisplay.Value,'All Lineaments (Auto)'))


                close Figure 1
                f1 = figure(1);
                f1.Name  = ('All Lineaments (Auto)');
                f1.WindowState = 'maximized';
                % global Number_of_Features_used_for_Lineaments_Detection
                global plotH_Auto
                global grm2_vector_Auto
                global XI % Ensure XI and YI are globally available as they are used in image() and axis()
                global YI %
                global YI_Real_Ratio % Ensure YI_Real_Ratio is globally available for daspect()

                DR = size(plotH_Auto, 4)
                plt1 = ceil(DR^0.5);
                plt2 = plt1;              

                % Sort the step‑filtering widths in ascending order
                [~, sortedIndices] = sort(grm2_vector_Auto);

                % Create a tab group
                tg = uitabgroup(f1);

                % Loop through each feature in sorted order
                for ii = 1:DR
                    idx = sortedIndices(ii);
                    t = uitab(tg,'Title',sprintf('Lineaments %d' ,ii));
                    
                    % Create axes directly in the tab
                    ax = uiaxes(t,'Units','normalized','Position',[0 0 1 1]);
                    
                    % Plot the image onto the specific axes (ax)
                    image(ax,plotH_Auto(:,:,:,idx),...
                        'XData',[min(XI(:)) max(XI(:))],...
                        'YData',[min(YI(:)) max(YI(:))]);
                    
                    ax.TickDir   = 'out';
                    ax.LineWidth = 1;
                    ax.Layer     = 'top';
                    
                    % Explicitly apply colormap to 'ax'
                    % 1) Build the exact blue→red colormap you used:
                    N      = 256;
                    reds   = linspace(0,1,N)';    % c from 0→1
                    greens = zeros(N,1);          % always zero
                    blues  = linspace(1,0,N)';    % 1–c from 1→0
                    myMap  = [reds, greens, blues];
                    colormap(ax, myMap); % <--- Changed from colormap(gca, myMap)
                    
                    % Explicitly apply caxis to 'ax'
                    caxis(ax, [0 1]); % <--- Changed from caxis([0 1])
                    
                    ax.YDir      = 'normal';
                    shading(ax,'interp');
                    view(ax,0,90);
                    grid(ax,'off');
                    % ax.XTick = [];
                    % ax.YTick = [];
                    ax.Box   = 'on';
                    axis(ax,'on','tight','equal'); % <--- axis also affects gca by default, better to be explicit or use properties
                    
                    title(ax,['Feature #',num2str(idx)]);
                    subtitle(ax,['w = ',num2str(grm2_vector_Auto(idx))]);
                    ax.FontSize   = 14;
                    
                    daspect(ax,[YI_Real_Ratio 1 1]);
                    
                    % Explicitly create colorbar for 'ax'
                    h_colorbar = colorbar(ax); % <--- Changed from colorbar(gca)
                    h_colorbar.Location    = 'eastoutside';
                    h_colorbar.Ticks       = [0 1];
                    h_colorbar.TickLabels  = {'Weak Faults','Strong Faults'};
                    h_colorbar.FontSize   = 12;
                    % h_colorbar.Label.String = 'Fault Strength (c)';
                end

            elseif (strcmp(app.LineDisplay.Value,'Curvilinearity Control (Auto)'))


                % Visualize step filtering widths
                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Curvilinearity Control (Auto)');
                f1.WindowState = 'maximized';

                global grm2_vector_Auto
                grm2_vector_s = size(grm2_vector_Auto);
                grm2_vector_s = grm2_vector_s(1,2);
                x_ax = 1:1:grm2_vector_s;
                bar(x_ax,grm2_vector_Auto,'black')
                text(x_ax, grm2_vector_Auto, num2str(grm2_vector_Auto'), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')

                title(['Average Step Filtering Width = ',num2str(mean(grm2_vector_Auto)),''])
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                curtick = get(gca, 'xTick');
                xticks(unique(round(curtick)));
                xlabel('Features')
                ylabel('Min D')
                ylim([0 (max(grm2_vector_Auto)+0.1*max(grm2_vector_Auto))])
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off


                elseif (strcmp(app.LineDisplay.Value,'Line Densities (Auto)'))
                    global densityMap_Auto


                close Figure 1
                f1 = figure(1);
                f1.Name  = ('Line Densities (Auto)');
                f1.WindowState = 'maximized';

                    plot(XI,YI)
                    imagesc(densityMap_Auto,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                    title('Lineaments Densities')
                    box on
                    set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                    colormap jet;
                    set(gca,'YDir','normal')
                    shading interp
                    view(0,90)
                    grid off
                    set(gcf,'GraphicsSmoothing','on')
                    daspect([YI_Real_Ratio 1 1])


            elseif (strcmp(app.LineDisplay.Value,'Lineaments (Auto)'))

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Lineaments (Auto)');
                f1.WindowState = 'maximized';

                global plotH1_rgb
                plot(XI,YI)
                image(plotH1_rgb,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('All Lineaments Stacked (Auto)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])                


            elseif (strcmp(app.LineDisplay.Value,'Deep Lineaments (Auto)'))

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Deep Lineaments (Auto)');
                f1.WindowState = 'maximized';

                global plotH1_rgb_Deep
                plot(XI,YI)
                image(plotH1_rgb_Deep,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('Deep Lineaments Stacked (Auto)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])

            elseif (strcmp(app.LineDisplay.Value,'Shallow Lineaments (Auto)'))

                % Step 7: Display stacked lineaments
                f1 = figure(1);
                f1.Name  = ('Shallow Lineaments (Auto)');
                f1.WindowState = 'maximized';

                global plotH1_rgb_Shallow
                plot(XI,YI)
                image(plotH1_rgb_Shallow,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                title('Shallow Lineaments Stacked (Auto)')
                box on
                set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                colormap jet;
                set(gca,'YDir','normal')
                shading interp
                view(0,90)
                grid off
                set(gcf,'GraphicsSmoothing','on')
                daspect([YI_Real_Ratio 1 1])


            elseif (strcmp(app.LineDisplay.Value,'Fbeta Score (Auto)'))

                global plotH1_rgb

                All_Trg_Matrix2 = FilterB(All_Trg_Matrix,sigma2);
                All_Trg_Matrix2 = imbinarize(All_Trg_Matrix2);
                All_Trg_Matrix2 = im2double(All_Trg_Matrix2);

                plotH1_rgb_size = size(plotH1_rgb);

                All_Trg_Matrix2 = imresize(All_Trg_Matrix2,[plotH1_rgb_size(1,1) plotH1_rgb_size(1,2)]);
                data1 = FilterB(All_Trg_Matrix2,sigma2);
                data1 = imbinarize(data1);
                data1 = im2double(data1);
                data1 = cat(3, data1, data1, data1);
                data2 = imcomplement(data1);
                plotH1_rgb2 = imcomplement(plotH1_rgb);
                data2_size = size(data2);


                % Read the binary images
                real_faults = (data1(:,:,1)+data1(:,:,2)+data1(:,:,3))/3;
                detected_faults = (plotH1_rgb2(:,:,1)+plotH1_rgb2(:,:,2)+plotH1_rgb2(:,:,3))/3;


                % Create a disk-shaped structuring element of the given radius
                se1 = strel('rectangle', [radius1 radius1]);
                se2 = strel('rectangle', [radius2 radius2]);

                % Dilate the real faults image to create a neighborhood of radius = 5 around the fault lines
                real_faults_neighborhood1 = imdilate(real_faults, se1);
                real_faults_neighborhood2 = imdilate(real_faults, se2);


                % Apply the neighborhood to the detected faults image
                detected_faults_neighborhood = detected_faults;

                % Flatten the neighborhood images into vectors for computation
                real_faults_neighborhood1 = real_faults_neighborhood1(:);
                real_faults_neighborhood2 = real_faults_neighborhood2(:);

                detected_faults_neighborhood = detected_faults_neighborhood(:);

                % True positives
                tp = sum(real_faults_neighborhood1 & detected_faults_neighborhood);
                % fprintf('True Positives =\n\n       %d\n', tp);

                % False positives
                fp1 = sum(~real_faults_neighborhood1 & detected_faults_neighborhood);
                fp2 = sum(~real_faults_neighborhood2 & detected_faults_neighborhood);

                fp = fp1 - fp2;
                % fprintf('False Positives =\n\n       %d\n', fp);

                % False negatives
                fn = sum(real_faults_neighborhood1 & ~detected_faults_neighborhood);
                % fprintf('False  Negatives =\n\n       %d\n', fn);

                % Precision
                format bank
                precision = tp / (tp + fp);
                % Precision = precision;


                % Recall
                format bank
                recall = tp / (tp + fn);
                % Recall = recall;

                % Fbeta Score
                format bank
                f1_score = 2 * ((precision * recall) / (precision + recall));
                fbeta_score1 = (1 + beta^2) * ((precision * recall) / ((beta^2 * precision) + recall));
                Fbeta = fbeta_score1;


                f1 = figure(1);
                f1.Name = 'Overlayed Images with Simulated Transparency';
                f1.WindowState = 'maximized';

                global densityMap_Auto

                % ----- Step 1: Normalize and blend the density map ----- %
                % Normalize the density map to the range [0,1]
                densityMapNorm = mat2gray(densityMap_Auto);

                % Set a blending factor for simulated transparency (0 = fully transparent, 1 = fully opaque)
                alpha_bg = 0.8;
                % Create a white background (all ones) that matches the size of densityMapNorm
                whiteBG = ones(size(densityMapNorm));

                % Blend the normalized density map with white to simulate transparency
                blendedMap = (1 - alpha_bg) * whiteBG + alpha_bg * densityMapNorm;

                % ----- Step 2: Display the blended density map ----- %
                % Use imagesc to display the blended density map with proper spatial referencing
                imagesc([min(XI(:)) max(XI(:))], [min(YI(:)) max(YI(:))], blendedMap);
                set(gca, 'YDir', 'normal');
                colormap(gca, jet);
                hold on;

                % ----- Step 3: Prepare the digitized lineaments (data2) ----- %
                % Ensure data2 is numeric and normalize it
                data2 = double(data2);
                data2Plot = mat2gray(data2);  % Normalize for display

                % (Optional) Create a binary mask from data2 for potential pixel-wise transparency
                mask = double(data2 > 0);
                % Uncomment the next two lines to check the mask's class and size if needed:
                % disp('Class of mask:'), disp(class(mask));
                % disp('Size of mask:'), disp(size(mask));

                % ----- Step 4: Overlay the digitized lineaments ----- %
                % Overlay the lineaments image with the same spatial referencing
                h2 = image('CData', data2Plot, ...
                    'XData', [min(XI(:)) max(XI(:))], ...
                    'YData', [min(YI(:)) max(YI(:))]);
                set(gca, 'YDir', 'normal');

                % Use a uniform (scalar) transparency for the lineaments:
                h2.AlphaData = 0.7;  % 0.8 means 80% opaque (20% transparent)

                % ----- Step 5: Final styling ----- %
                title(['Extracted Lineaments (Auto), F-Beta Score = ', num2str(mean(fbeta_score1))]);
                colorbar;
                set(gca, 'TickDir', 'out', 'LineWidth', 1, 'Layer', 'top');
                view(0, 90);
                grid off;
                axis on;
                box on;
                daspect([YI_Real_Ratio 1 1]);


            elseif (strcmp(app.LineDisplay.Value,'Rose Diagram (Auto)'))

                % Rose Diagram
                global Orient
                f1 = figure(1);
                f1.Name  = ('RoseD Extracted (Auto)');
                f1.WindowState = 'maximized';
                % [orientations360] = createRoseDiagrams(BW1);

                % Create subplots for the rose diagram
                polarhistogram(deg2rad(Orient), 36); % 36 bins for 10-degree intervals
                title('Rose Diagram: Extracted Lineaments (Auto)');
                grid on


                elseif (strcmp(app.LineDisplay.Value,'BHO Performance (Auto)'))

                    % Create a new figure
                    global ObjectiveMinimumTrace
                    global EstimatedObjectiveMinimumTrace
                    f1 = figure(1);
                    f1.Name = 'Bayesian Hyperparameter Optimization Performance';
                    f1.WindowState = 'maximized';
                    hold on
                    % Plot the minimum objective function value at each iteration
                    plot(EstimatedObjectiveMinimumTrace, 'k', 'LineWidth', 0.25);
                    hold on;
                    plot(ObjectiveMinimumTrace, 'o', 'MarkerSize', 2, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'r');
                    legend('Estimated', 'Observed', 'Location', 'best');
                    hold off;
                    xlabel('Iteration');
                    ylabel('Min Objective');
                    title('Performance of Lineament Extraction based on BHO');
                    grid off;

                    elseif (strcmp(app.LineDisplay.Value,'BHO Progress (Auto)'))
                    % global numeric_data0
                    global vals
                    global Min_Val
                    global Max_Val
                    global Var2
                    global lower_value
                    global upper_value
                    global Mean_Val
                    global End_Val
                    global XTraceTable
                    global BHO_Iter
                    global numVars


                        % Create a new figure
                        f1 = figure(1);
                        f1.Name = 'Bayesian Hyperparameter Optimization Progress';
                        f1.WindowState = 'maximized';
                        hold on

for i = 1:numVars
    subplot(5, 2, i);
    
    % Get the data from the i-th column
    columnData = XTraceTable{:,i};
    % Robustly convert the column to a numeric array for plotting
    if isnumeric(columnData)
        numeric_data0 = columnData; % Already numeric, use it directly
    else
        % If not numeric (i.e., the 'orderx' categorical column), convert it
        numeric_data0 = str2double(string(columnData));
    end

    % Plot the value of this variable at each iteration
    plot(numeric_data0, 'k');
    hold on;
    
    % --- THE FOLLOWING TWO LINES HAVE BEEN REMOVED ---
    % H_Line = vals(1,i); 
    % yline(H_Line, '--', 'Color', [0.85 0.33 0.1]);
    
    axis tight;
    
    % Add a small margin to ylim to prevent data touching the border
    data_range = range(numeric_data0);
    if data_range == 0, data_range = 1; end % Avoid range of 0
    ylim([min(numeric_data0) - 0.1 * data_range, ...
          max(numeric_data0) + 0.1 * data_range]);
    xlim([1, BHO_Iter]);

    title(XTraceTable.Properties.VariableNames{i}, 'Interpreter', 'none'); % Use 'Interpreter', 'none' to avoid issues with underscores
    xlabel('Iteration');
    ylabel('Value');
    hold off; % Added hold off for good practice in loops
end


elseif (strcmp(app.LineDisplay.Value,'Feature Correlation Matrix (Auto)'))
    %% Random Forest Hyperparameter Importance
    % Visualize correlation matrix
    f1 = figure(1);
    f1.Name = 'Importance Correlation Matrix';
    f1.WindowState = 'maximized';
    
    global XTraceTable

    % CORRECTED: Robustly extract mixed-type data into a numeric matrix
    featureNames = {'WSFR', 'NAngles', 'orderx', 'Z','w','VSFW', 'NSFA', 'SLC', 'FDissimilarity', 'FSaliency'};
    tempTable = XTraceTable(:, featureNames);
    tempTable.orderx = str2double(string(tempTable.orderx));
    X = table2array(tempTable);
    
    % The rest of your code is now correct
    corrMatrix = corr(X);
    heatmap(featureNames, featureNames, corrMatrix, ...
        'Title', 'Feature Correlation Matrix', 'ColorbarVisible', 'on');


elseif (strcmp(app.LineDisplay.Value,'Importance (Auto)'))
    
    %% Random Forest Hyperparameter Importance
    % Plot MDI-based feature importance
    f1 = figure(1);
    f1.Name = 'Hyperparameter Importance by MDI';
    f1.WindowState = 'maximized';
    
    global XTraceTable
    global EstimatedObjectiveMinimumTrace
    
    % Correctly extract mixed-type data into a numeric matrix
    featureNames = {'WSFR', 'NAngles', 'orderx', 'Z','w','VSFW', 'NSFA', 'SLC', 'FDissimilarity', 'FSaliency'};
    tempTable = XTraceTable(:, featureNames);
    tempTable.orderx = str2double(string(tempTable.orderx));
    X = table2array(tempTable);
    
    y = EstimatedObjectiveMinimumTrace;
    
    % Normalize features to ensure comparability (add epsilon to prevent division by zero)
    X = (X - mean(X, 1)) ./ (std(X, 0, 1) + 1e-8);
    
    % Define the number of trees for the Random Forest
    numTrees = 500;
    
    % Train a Random Forest model using TreeBagger
    forestModel = TreeBagger(numTrees, X, y, 'Method', 'regression', ...
        'OOBPrediction', 'on', 'OOBVarImp', 'on', 'MinLeafSize', 5);
        
    % --- Calculate feature importance using Mean Decrease in Impurity (MDI) as requested ---
    featureImportanceMDI = forestModel.DeltaCriterionDecisionSplit;
    
    bar(featureImportanceMDI);
    title('Hyperparameter Importance Based on Mean Decrease in Impurity (MDI)');
    xlabel('Hyperparameter');
    ylabel('Importance Score (MDI)');
    xticks(1:length(featureNames));
    xticklabels(featureNames);
    xtickangle(45); % Rotate labels to prevent overlap  

            end

        end

        % Value changed function: LineDisplay
        function LineDisplayValueChanged(app, event)

        end

        % Value changed function: LinePlotFilter
        function LinePlotFilterValueChanged(app, event)

        end

        % Button pushed function: Export_CSV_2
        function Export_CSV_2ButtonPushed(app, event)

            global XI
            global YI
            global Result3D
            global Result3D_Auto
            global All_Data_Matrix
            global Spectral_PCA_Features_Matrix
            global Spectral_PCA_Features_Matrix_Auto

            
            global densityMap
            global densityMap_Auto

            global Shape
            global Shape_Deep
            global Shape_Shallow

            global Shape_Auto
            global Shape_Deep_Auto
            global Shape_Shallow_Auto


            ExpN = app.ExportNum.Value;


            value5 = app.PDataButton.Value;

            value9 = app.SelectedManualButton.Value;
            value17 = app.CWTManualButton.Value; % CWTAutoButton     SelectedAutoButton

            value14 = app.CWTAutoButton.Value;
            value15 = app.SelectedAutoButton.Value;

            value18 = app.DensitiesManualButton.Value;
            value19 = app.DensitiesAutoButton.Value;
            
            value20 = app.AllLinesManualButton.Value;
            value21 = app.DeepLinesManualButton.Value;
            value22 = app.ShallowLinesManualButton.Value;
            value23 = app.AllLinesAutoButton.Value;
            value24 = app.DeepLinesAutoButton.Value;
            value25 = app.ShallowLinesAutoButton.Value;


            % Append selected images to the cell array based on Radio bottons values


            if value5 == 1
                Im = All_Data_Matrix;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = [XI(:) YI(:) ExpImage(:)];
                % ExpImage_Size1 = size(ExpImage)
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs
                % ExpImage_Size2 = size(ExpImage)

                Custom_csvwrite('Data.csv', ExpImage,ExpN);

            end


            if value9 == 1
                Im = Spectral_PCA_Features_Matrix;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = [XI(:) YI(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('Selected_Manual.csv', ExpImage,ExpN);
            end


            if value17 == 1
                Im = Result3D;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = [XI(:) YI(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('CWT_Manual.csv', ExpImage,ExpN);
            end


            if value18 == 1
                Im = densityMap;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end


                [rows, cols] = size(ExpImage);
                % Regenerate new coordinate grid with same extent and new resolution
                x = linspace(min(XI(:)), max(XI(:)), cols);
                y = linspace(min(YI(:)), max(YI(:)), rows);  % Y goes top-down in images

                [XI_Resized, YI_Resized] = meshgrid(x, y);

                min(XI(:))
                max(XI(:))

                min(YI(:))
                max(YI(:))      

                min(XI_Resized(:))
                max(XI_Resized(:))

                min(YI_Resized(:))
                max(YI_Resized(:))    


                ExpImage = [XI_Resized(:) YI_Resized(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('Lineaments Densities Manual.csv', ExpImage,ExpN);
            end


            if value19 == 1
                Im = densityMap_Auto;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end


                [rows, cols] = size(ExpImage);
                % Regenerate new coordinate grid with same extent and new resolution
                x = linspace(min(XI(:)), max(XI(:)), cols);
                y = linspace(min(YI(:)), max(YI(:)), rows);  % Y goes top-down in images

                [XI_Resized, YI_Resized] = meshgrid(x, y);


                ExpImage = [XI_Resized(:) YI_Resized(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('Lineaments Densities Auto.csv', ExpImage,ExpN);
            end


            if value20 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape(i).X;
                    yCoords = Shape(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'All Lineaments (Manual).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end

            if value21 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape_Deep)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape_Deep(i).X;
                    yCoords = Shape_Deep(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'Deep Lineaments (Manual).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end


            if value22 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape_Shallow)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape_Shallow(i).X;
                    yCoords = Shape_Shallow(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'Shallow Lineaments (Manual).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end


            if value14 == 1
                Im = Result3D_Auto;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = [XI(:) YI(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('CWT_Auto.csv', ExpImage,ExpN);
            end


            if value15 == 1
                Im = Spectral_PCA_Features_Matrix_Auto;

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = [XI(:) YI(:) ExpImage(:)];
                ExpImage(any(isnan(ExpImage), 2), :) = []; % Removing the NaNs

                Custom_csvwrite('Selected_Auto.csv', ExpImage,ExpN);
            end


            if value23 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape_Auto)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape_Auto(i).X;
                    yCoords = Shape_Auto(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'All Lineaments (Auto).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end

            if value24 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape_Deep_Auto)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape_Deep_Auto(i).X;
                    yCoords = Shape_Deep_Auto(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'Deep Lineaments (Auto).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end


            if value25 == 1
                % Extract numeric coordinates from the Shape structure
                allCoords = [];
                for i = 1:length(Shape_Shallow_Auto)
                    % Get the coordinates for the i-th shape
                    xCoords = Shape_Shallow_Auto(i).X;
                    yCoords = Shape_Shallow_Auto(i).Y;

                    % Create an identifier for the shape (optional)
                    shapeID = repmat(i, numel(xCoords), 1);

                    % Concatenate shape ID, x, and y coordinates
                    allCoords = [allCoords; shapeID, xCoords(:), yCoords(:)];
                end

                % Define default file name
                defaultFileName = 'Shallow Lineaments (Auto).csv';

                % Let the user select the path and file name to save the CSV (with default provided)
                [filename, pathname] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save CSV File As', defaultFileName);

                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the file save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    % Save the numeric matrix to CSV (writematrix is recommended for newer MATLAB versions)
                    writematrix(allCoords, fullFileName);
                    disp(['CSV file saved as: ', fullFileName]);
                end

            end


        end

        % Button pushed function: Export_GRD_2
        function Export_GRD_2ButtonPushed(app, event)

            % Get user-defined coordinates from the GUI
            Xmin = app.MinLonEditField.Value; % Get from GUI
            Xmax = app.MaxLonEditField.Value; % Get from GUI
            Ymin = app.MinLatEditField.Value; % Get from GUI
            Ymax = app.MaxLatEditField.Value; % Get from GUI


            global XI


            global All_Data_Matrix
            global Spectral_PCA_Features_Matrix
            global Spectral_PCA_Features_Matrix_Auto
            global Result3D
            global Result3D_Auto

            global densityMap
            global densityMap_Auto

            global Shape
            global Shape_Deep
            global Shape_Shallow

            global Shape_Auto
            global Shape_Deep_Auto
            global Shape_Shallow_Auto

            ExpN = app.ExportNum.Value;


            value5 = app.PDataButton.Value;
            value9 = app.SelectedManualButton.Value;
            value17 = app.CWTManualButton.Value;

            value14 = app.CWTAutoButton.Value;
            value15 = app.SelectedAutoButton.Value;


            value18 = app.DensitiesManualButton.Value;
            value19 = app.DensitiesAutoButton.Value;
            value20 = app.AllLinesManualButton.Value;
            value21 = app.DeepLinesManualButton.Value;
            value22 = app.ShallowLinesManualButton.Value;
            value23 = app.AllLinesAutoButton.Value;
            value24 = app.DeepLinesAutoButton.Value;
            value25 = app.ShallowLinesAutoButton.Value;


            % Append selected images to the cell array based on Radio bottons values


            if value5 == 1
                Im = All_Data_Matrix;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['Data_',num2str(ExpN),'.tif' '']);

            end


            if value9 == 1
                Im = Spectral_PCA_Features_Matrix;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['Selected_Manual',num2str(ExpN),'.tif' '']);
            end


            if value17 == 1
                Im = Result3D;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['CWT_Manual',num2str(ExpN),'.tif' '']);
            end


            if value18 == 1
                Im = densityMap;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['Lineaments Densities Manual']);
            end

            if value19 == 1
                Im = densityMap_Auto;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['Lineaments Densities Auto']);
            end

            if value20 == 1
                Im = Shape;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'All Lineaments (Manual).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end


            if value21 == 1
                Im = Shape_Deep;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'Deep Lineaments (Manual).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end


            if value22 == 1
                Im = Shape_Shallow;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'Shallow Lineaments (Manual).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end


            if value15 == 1
                Im = Spectral_PCA_Features_Matrix_Auto;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['Selected_Auto',num2str(ExpN),'.tif' '']);
            end


            if value14 == 1
                Im = Result3D_Auto;  

                if ndims(Im) == 2
                    ExpImage = Im;

                else
                    ExpImage = Im(:,:,ExpN);
                end

                ExpImage = flip(ExpImage, 1);
                geotiff(ExpImage,Xmin,Xmax,Ymin,Ymax,['CWT_Auto',num2str(ExpN),'.tif' '']);
            end


            if value23 == 1
                Im = Shape_Auto;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'All Lineaments (Auto).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end


            if value24 == 1
                Im = Shape_Deep_Auto;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'Deep Lineaments (Auto).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end


            if value25 == 1
                Im = Shape_Shallow_Auto;  % Your structure with lineaments

                % Let the user pick filename and path, with a default suggestion
                [filename, pathname] = uiputfile('*.shp', 'Save Shapefile As', 'Shallow Lineaments (Auto).shp');

                if ischar(filename)  % User clicked "Save" instead of cancel
                    shapefileName = fullfile(pathname, filename);  % Use the actual selected filename
                    shapewrite(Im, shapefileName);
                    disp(['Shapefile saved to: ', shapefileName]);
                else
                    disp('User cancelled shapefile export.');
                end

            end

        end

        % Button pushed function: AutoSpacing
        function AutoSpacingButtonPushed(app, event)
            global Aut
            app.SpacingArcSecondsEditField.Value = Aut;
        end

        % Button pushed function: Lineaments_Auto_2
        function Lineaments_Auto_2ButtonPushed(app, event)
    warning('off','all')
    rng(42); % Replace 42 with any fixed integer seed. This ensures that all random number generators
    % in MATLAB produce the same sequence of random numbers for every run.
    % Define the figure numbers to be checked and closed
    figureNumbers = 1:50;
    % Loop through each figure number and check if it exists, then close it
    for i = figureNumbers
        if ishandle(findobj('Type', 'figure', 'Number', i))
            close(i); % Close the figure if it exists
        end
    end

    disp('Automatic Unsupervised Lineaments Extraction with Hyper Parameter Optimization');


    % --- Comprehensive Cleaning Block Using All Three Commands ---

    % Step 1: Clear local workspace variables from any previous script run or debug session.
    % (This matches the structure of your original code, with the missing variables added).
    clear numeric_data0 vals Min_Val Max_Val Var2 lower_value upper_value Mean_Val End_Val
    clear XTraceTable BHO_Iter numVars PCA_Type EstimatedObjectiveMinimumTrace ObjectiveMinimumTrace LastIters

    % Step 2: Clear specific global variables that store results from the previous run.
    % (This is the most critical step. ObjectiveMinimumTrace and LastIters have been added).
    clear global numeric_data0 vals Min_Val Max_Val Var2 lower_value upper_value Mean_Val End_Val
    clear global XTraceTable BHO_Iter numVars EstimatedObjectiveMinimumTrace ObjectiveMinimumTrace LastIters

    % Step 3: Use clearvars for other specific global variables, as in your original code.
    clearvars -global Line_Res grm

    % --- End of Cleaning Block ---


    global CWT_Input_Features_Matrix_Auto
    global All_Data_Matrix
    global Number_of_Features_used_for_Lineaments_Detection_Auto
    global X_Pix
    global Y_Pix
    global vals
    global Min_Val
    global Max_Val
    global Var2
    global lower_value
    global upper_value
    global Mean_Val
    global End_Val
    global XTraceTable
    global BHO_Iter
    global numVars
    global EstimatedObjectiveMinimumTrace
    X_Pix = app.Xn.Value;
    Y_Pix = app.Yn.Value;
    % global sigma2_auto
    sigma2_auto = str2num(app.LineImFilterR_Auto.Value); % Image Filter Ratio
    % LMax_Threshold = app.LMax_AutoLine.Value;
    SDF = app.ScaleDilationFactor_Auto.Value; % Scale Dilation Factor
    SDF = str2double(SDF);
 
    global grm
    Line_Res = str2num(app.LineRes_Auto.Value);
    DR = (app.CWTReductionto_Auto.Value); % Feature Selection Dimensionality Reduction
    N_Scales = str2num(app.NumberofScales_Auto.Value); % Number of CWT Scales
   
    % Define search range for 'w' based on GUI value
    grm = str2num(app.w_center.Value); % Get the initial center value
    w_rang = str2num(app.w_range.Value);
    min_w = w_rang(1) * grm;
    max_w = w_rang(2) * grm;
    % Ensure min_w is at least 2, as smaller filter widths can be problematic
    if min_w < 2
        min_w = 2;
    end
    if max_w <= min_w
        max_w = min_w + 10; % Ensure a valid range
    end


    % Use 'real' and 'integer' types for more powerful optimization
    vars = [
        % Use 'real' for continuous floating-point values
        optimizableVariable('WSFR',           str2num(app.sigma_range.Value), 'Type', 'real')
        optimizableVariable('Z',              str2num(app.Z_range.Value), 'Type', 'real')
        optimizableVariable('VSFW',           str2num(app.VSFW_range.Value), 'Type', 'real')
        optimizableVariable('FDissimilarity', str2num(app.Dissimilarity_range.Value), 'Type', 'real')
        optimizableVariable('FSaliency',      str2num(app.Saliency_range.Value), 'Type', 'real')
        % Use 'integer' for discrete numerical values (like counts or sizes)
        optimizableVariable('NAngles', str2num(app.CWTAngles__range.Value), 'Type', 'integer')
        optimizableVariable('w',       [round(min_w), round(max_w)], 'Type', 'integer') % 'w' should be an integer
        optimizableVariable('NSFA',    str2num(app.StepFAngles_range.Value), 'Type', 'integer')
        optimizableVariable('SLC',     str2num(app.HystT_range.Value), 'Type', 'integer')
        % Use 'categorical' ONLY for true non-numeric or non-ordered choices
        optimizableVariable('orderx', {'0', '1'}, 'Type', 'categorical')
    ];

    % The objective function now handles the new numeric types directly
    fun = @(x) Lineaments_Auto7(CWT_Input_Features_Matrix_Auto, All_Data_Matrix, ...
        x.WSFR, ...                 % Already numeric
        x.NAngles, ...              % Already numeric
        str2double(string(x.orderx)), ... % The ONLY one that needs conversion
        x.Z, ...                    % Already numeric
        x.w, ...                    % Already numeric
        x.VSFW, ...                 % Already numeric
        x.NSFA, ...                 % Already numeric
        x.SLC, ...                  % Already numeric
        x.FDissimilarity, ...       % Already numeric
        x.FSaliency, ...            % Already numeric
        N_Scales, Line_Res, DR, SDF, sigma2_auto);

    global BHO_Iter, global LastIters
    BHO_Iter = str2num(app.BHOIter.Value);
    LastIters = 0.80;
    results = bayesopt(fun, vars, ...
        'MaxObjectiveEvaluations', BHO_Iter, ...
        'PlotFcn', []); % Disable all plotting
    
    % Results processing to handle the mixed-type table
    zbest_table = results.XAtMinObjective;  % Get the best point as a table

    % Access each optimized value by its variable name from the table
    val1 = zbest_table.WSFR;
    val2 = zbest_table.NAngles;
    val3 = str2double(string(zbest_table.orderx)); % Convert only the categorical variable
    val4 = 1 - val3; % Simplified logic for the dependent variable
    val5 = zbest_table.Z;
    val6 = zbest_table.w;
    val7 = zbest_table.VSFW;
    val8 = zbest_table.NSFA;
    val9 = zbest_table.SLC;
    val10 = zbest_table.FDissimilarity;
    val11 = zbest_table.FSaliency;

    % Update the GUI elements
    app.WSFR_Auto.Value = string(val1);
    app.NumberofAngles_Auto.Value = string(val2);
    app.OrderXn_Auto.Value = val3;
    app.OrderYm_Auto.Value = val4;
    app.PoissonUPwardContinuation_Auto.Value = val5;
    app.SFW_Auto.Value = string(val6);
    app.VSFW_Auto.Value = string(val7);
    app.LineAng_Auto.Value = string(val8);
    app.SLC_Auto.Value = string(val9);
    app.Dissimilarity_Auto.Value = val10;
    app.FeatureSaliency_Auto.Value = val11;

    % Create the final numeric array of values for your next function
    Values = [val1, val2, val3, val4, val5, val6, val7, val8, val9, val10, val11];
    vals = Values;
    
    % Extract the variables at each iteration
    XTraceTable = results.XTrace;
    % Get the number of variables
    numVars = width(XTraceTable);
    
    % Define the Excel file name
    outputFileName = 'BHO_Progress.xlsx';
    % Extract the number of iterations
    numIterations = (1:size(results.ObjectiveTrace,1))'; % Use actual number of completed iterations
    % Extract the observed and predicted minimum objective function errors
    observedErrors = results.ObjectiveMinimumTrace;
    predictedErrors = results.EstimatedObjectiveMinimumTrace;
    objectiveValues = results.ObjectiveTrace;

    % Convert XTraceTable to a table for export
    resultsTable = XTraceTable;
    % Add the iteration number as the first column
    resultsTable = addvars(resultsTable, numIterations, 'Before', 1, 'NewVariableNames', 'Iteration');
    % Add the observed, predicted errors, and BHO objective values as the last columns
    resultsTable = addvars(resultsTable, observedErrors(1:length(numIterations)), ...
        predictedErrors(1:length(numIterations)), objectiveValues(1:length(numIterations)), ...
        'After', width(resultsTable), ...
        'NewVariableNames', {'ObservedError', 'PredictedError', 'objectiveValues'});
    
    % Write the table to an Excel file
    writetable(resultsTable, outputFileName);
    
    % Create a fully numeric table for analysis
    numericAnalysisTable = table();
    for colName = resultsTable.Properties.VariableNames
        colData = resultsTable.(colName{1});
        if iscategorical(colData) || iscell(colData)
            numericAnalysisTable.(colName{1}) = str2double(string(colData));
        else
            numericAnalysisTable.(colName{1}) = colData;
        end
    end
    numericTable = table2array(numericAnalysisTable);

    % Find the minimum value in the 'PredictedError' column
    predictedErrorColIndex = find(strcmp(numericAnalysisTable.Properties.VariableNames, 'PredictedError'));
    if ~isempty(predictedErrorColIndex)
        [minValue, minIndex] = min(numericTable(:, predictedErrorColIndex));
        disp(['Minimum value in PredictedError column: ', num2str(minValue)]);
        disp(['Index of Minimum value: ', num2str(minIndex)]);
    else
        disp('PredictedError column not found for analysis.');
        minIndex = 1; % Failsafe
    end
    
    % --- CORRECTED: Analysis loop to handle numeric and categorical data ---
    % This loop now correctly handles the mixed data types in XTraceTable.
    for i = 1:numVars
        % Get the data from the i-th column of the trace table
        columnData = XTraceTable{:, i};

        % Robustly convert the column to a numeric array for statistical analysis
        if isnumeric(columnData)
            numeric_data = columnData; % Already numeric, use it directly
        else
            % If not numeric (i.e., it's the 'orderx' categorical column), convert it
            numeric_data = str2double(string(columnData));
        end
        
        % Perform statistical calculations on the guaranteed-to-be-numeric data
        % Added checks to prevent division by zero or NaN results if std/mean are 0
        if mean(numeric_data) ~= 0
            CV2 = std(numeric_data) / mean(numeric_data);
        else
            CV2 = 0;
        end
        
        Var1(1,i) = CV2;
        Var2 = round(100*CV2);
        Mean_Val = median(numeric_data);
        Min_Val = min(numeric_data);
        Max_Val = max(numeric_data);
        lower_value = prctile(numeric_data, 15);
        upper_value = prctile(numeric_data, 85);
        
        % Ensure minIndex is valid before using it
        if minIndex <= length(numeric_data)
            End_Val = numeric_data(minIndex);
        else
            End_Val = numeric_data(end); % Failsafe to last value
        end
    end
    % --- End of corrected analysis loop ---

    NScales_Auto = str2double(app.NumberofScales_Auto.Value);
    NAngles_Auto = str2double(app.NumberofAngles_Auto.Value);
    if ndims(CWT_Input_Features_Matrix_Auto) == 2
        CWT_Input_Features_Matrix_Size = 1;
        WCT_F_2 = CWT_Input_Features_Matrix_Size * NScales_Auto * NAngles_Auto; % Number of input/output CWT features
    elseif ndims(CWT_Input_Features_Matrix_Auto) == 3
        CWT_Input_Features_Matrix_Size = size(CWT_Input_Features_Matrix_Auto, 3);
        WCT_F_2 = CWT_Input_Features_Matrix_Size * NScales_Auto * NAngles_Auto;
    end
    app.NumCWTFeatures_Auto.Value = num2str(WCT_F_2);
    fprintf('\n\n--- Bayesian Optimized Hyperparameters ---\n\n')
    fprintf('\n\n***************************************\n\n')
    disp(['Wavelet Smoothness Filter Ratio: ', num2str(val1)]);
    disp(['Number of CWT Angles: ', num2str(val2)]);
    disp(['Derivative Order in X Direction: ', num2str(val3)]);
    disp(['Derivative Order in Y Direction: ', num2str(val4)]);
    disp(['Poisson Kernel Upward Continuation Hight: ', num2str(val5)]);
    disp(['Step Filtering Width: ', num2str(val6)]);
    disp(['Variability of Step Filtering Width: ', num2str(val7)]);
    disp(['Number of Step Filtering Angles: ', num2str(val8)]);
    disp(['Strongest Lineament Componments (%): ', num2str(val9)]);
    disp(['CWT Feature Dissimilarity: ', num2str(val10)]);
    disp(['CWT Feature Saliency (%): ', num2str(val11)]);
    fprintf('\n\n***************************************\n\n')
    % Extract the minimum observed objective function value at each iteration
    global ObjectiveMinimumTrace
    global EstimatedObjectiveMinimumTrace
    ObjectiveMinimumTrace = results.ObjectiveMinimumTrace;
    EstimatedObjectiveMinimumTrace = results.EstimatedObjectiveMinimumTrace;
    %% Extracted Lineaments
    BHO_Lines_4(CWT_Input_Features_Matrix_Auto, All_Data_Matrix, Values, Number_of_Features_used_for_Lineaments_Detection_Auto, N_Scales, Line_Res, DR, SDF, sigma2_auto)

        end

        % Button pushed function: SpectralInputsMerge_Auto
        function SpectralInputsMerge_AutoButtonPushed(app, event)


            fprintf('\n\n--- Preparing the Inputs for Automatic Lineament Extraction ---\n\n');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Merging Inputs for Automatic Lineament Extraction...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            frames(end).setAlwaysOnTop(1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            global All_Data_Matrix
            value5 = app.SpectralInputsPointD_Auto.Value;
            clearvars Input5
            clear input5
            clearvars fh1
            clearvars fh2
            clearvars fh3


            % Initialize a cell array to hold the selected image matrices
            selectedImages = {};

            % Append selected images to the cell array based on checkbox values
            if value5 == 1
                if ndims(All_Data_Matrix) == 2
                    selectedImages{end+1} = All_Data_Matrix(:,:,1); % Convert 2D to 3D
                else
                    selectedImages{end+1} = All_Data_Matrix;
                end
            end

            % Combine all selected images into one single 3D matrix

            global CWT_Input_Features_Matrix_Auto

            if ~isempty(selectedImages)
                CWT_Input_Features_Matrix_Auto = cat(3, selectedImages{:});
            else
                disp('Error: No images selected.');
            end


            CWT_Input_Features_Matrix_Size1 = size(CWT_Input_Features_Matrix_Auto);
            CWT_Input_Features_Matrix_Size2 = size(CWT_Input_Features_Matrix_Size1); CWT_Input_Features_Matrix_Size2 = CWT_Input_Features_Matrix_Size2(1,2);

            if CWT_Input_Features_Matrix_Size2 == 2

                CWT_Input_Features_Matrix_Size = 1;

            elseif CWT_Input_Features_Matrix_Size2 == 3

                CWT_Input_Features_Matrix_Size = size(CWT_Input_Features_Matrix_Auto);
                CWT_Input_Features_Matrix_Size = CWT_Input_Features_Matrix_Size(1,3);
            end


            global Number_of_Features_used_for_Lineaments_Detection_Auto
            Number_of_Features_used_for_Lineaments_Detection_Auto = CWT_Input_Features_Matrix_Size;


            Line_Res = str2num(app.LineRes_Auto.Value);
            global X_Pix
            global Y_Pix
            X_Pix = app.Xn.Value;
            Y_Pix = app.Yn.Value;

            global grm
            if isempty(app.w_center.Value)
                grm = (0.15 * min(X_Pix * Line_Res, Y_Pix * Line_Res));
                app.w_center.Value = num2str(grm);
                grm = double(grm);
            else
            grm = str2double(app.w_center.Value);    % grammes (w): SFW
            end


            app.Xn_Auto.Value = X_Pix * Line_Res;
            app.Yn_Auto.Value = Y_Pix * Line_Res; 


            disp(['Number of Inputs for Automatic Lineament Extraction: ', num2str(CWT_Input_Features_Matrix_Size)]);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            close(h)
            pause(0.01);

        end

        % Value changed function: MotherWaveletDropDown_Auto
        function MotherWaveletDropDown_AutoValueChanged(app, event)

            fprintf('\n\n--- Selecting Mother Wavelet ---\n\n');

            clearvars pop_choice2

            global pop_choice3

            pop_choice3 = app.MotherWaveletDropDown_Auto.Value;


            if (strcmp(pop_choice3,'Derivatives of Gaussian (Asymmetric)'))
                pop_choice3 = cell({'gaus'}); pop_choice3  = cell2mat(pop_choice3);
                disp('Mother Wavelet: Derivatives of Gaussian (Asymmetric)');


            elseif (strcmp(pop_choice3,'Derivatives of Poisson (Asymmetric)'))
                pop_choice3 = cell({'derpoisson2d'}); pop_choice3  = cell2mat(pop_choice3);
                disp('Mother Wavelet: Derivatives of Poisson (Asymmetric)');

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BWTO2Dver10UIFigure = uifigure('Visible', 'off');
            app.BWTO2Dver10UIFigure.Name = 'BWTO2D ver. 1.0 -- Dr Bahman Abbassi, University of Quebec: bahman.abbassi@uqat.ca';
            app.BWTO2Dver10UIFigure.Icon = fullfile(pathToMLAPP, 'Icon2.jpg');
            app.TabGroup = uitabgroup(app.BWTO2Dver10UIFigure);
            app.DataSetsTab = uitab(app.TabGroup);
            app.DataSetsTab.Title = 'Data Sets';
            app.CoordinatesPanel = uipanel(app.DataSetsTab);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.MinLonEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.MinLonEditFieldLabel.Text = 'Min Lon';
            app.MinLonEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.MinLonEditField.AllowEmpty = 'on';
            app.MinLonEditField.ValueChangedFcn = createCallbackFcn(app, @MinLonEditFieldValueChanged, true);
            app.MinLonEditField.Value = [];
            app.MaxLonEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.MaxLonEditFieldLabel.Text = 'Max Lon';
            app.MaxLonEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.MaxLonEditField.AllowEmpty = 'on';
            app.MaxLonEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLonEditFieldValueChanged, true);
            app.MaxLonEditField.Value = [];
            app.MaxLatEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.MaxLatEditFieldLabel.Text = 'Max Lat';
            app.MaxLatEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.MaxLatEditField.AllowEmpty = 'on';
            app.MaxLatEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLatEditFieldValueChanged, true);
            app.MaxLatEditField.Value = [];
            app.MinLatEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.MinLatEditFieldLabel.Text = 'Min Lat';
            app.MinLatEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.MinLatEditField.AllowEmpty = 'on';
            app.MinLatEditField.ValueChangedFcn = createCallbackFcn(app, @MinLatEditFieldValueChanged, true);
            app.MinLatEditField.Value = [];
            app.CoordMethod = uidropdown(app.CoordinatesPanel);
            app.CoordMethod.Items = {'Method', 'Rectangular coordinates', 'Automatic polygonization'};
            app.CoordMethod.ValueChangedFcn = createCallbackFcn(app, @CoordMethodValueChanged, true);
            app.CoordMethod.Value = 'Method';
            app.Polygonize = uibutton(app.CoordinatesPanel, 'push');
            app.Polygonize.ButtonPushedFcn = createCallbackFcn(app, @PolygonizeButtonPushed, true);
            app.Polygonize.Text = 'Polygonize';
            app.DataFilterEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.DataFilterEditFieldLabel.Text = 'Data  Filter';
            app.DataFilterEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.DataFilterEditField.ValueChangedFcn = createCallbackFcn(app, @DataFilterEditFieldValueChanged, true);
            app.SpacingArcSecondsEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.SpacingArcSecondsEditFieldLabel.Text = 'Spacing (Arc-Seconds)';
            app.SpacingArcSecondsEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.SpacingArcSecondsEditField.AllowEmpty = 'on';
            app.SpacingArcSecondsEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingArcSecondsEditFieldValueChanged, true);
            app.SpacingArcSecondsEditField.Value = 10;
            app.AutoSpacing = uibutton(app.CoordinatesPanel, 'push');
            app.AutoSpacing.ButtonPushedFcn = createCallbackFcn(app, @AutoSpacingButtonPushed, true);
            app.AutoSpacing.Text = 'Auto';
            app.DataSetsPanel = uipanel(app.DataSetsTab);
            app.DataSetsPanel.Title = 'Data Sets';
            app.PDataLoad = uidropdown(app.DataSetsPanel);
            app.PDataLoad.Items = {'Regular Interp', 'Scattered Interp'};
            app.PDataLoad.ValueChangedFcn = createCallbackFcn(app, @PDataLoadValueChanged, true);
            app.PDataLoad.Value = 'Regular Interp';
            app.XnLabel = uilabel(app.DataSetsPanel);
            app.XnLabel.Interpreter = 'latex';
            app.XnLabel.Text = '$$X_n$$';
            app.Xn = uieditfield(app.DataSetsPanel, 'numeric');
            app.Xn.AllowEmpty = 'on';
            app.Xn.ValueChangedFcn = createCallbackFcn(app, @XnValueChanged3, true);
            app.Xn.Editable = 'off';
            app.Xn.Value = [];
            app.YnLabel = uilabel(app.DataSetsPanel);
            app.YnLabel.Interpreter = 'latex';
            app.YnLabel.Text = '$$Y_n$$';
            app.Yn = uieditfield(app.DataSetsPanel, 'numeric');
            app.Yn.AllowEmpty = 'on';
            app.Yn.ValueChangedFcn = createCallbackFcn(app, @YnValueChanged3, true);
            app.Yn.Value = [];
            app.PointDataButton = uibutton(app.DataSetsPanel, 'push');
            app.PointDataButton.ButtonPushedFcn = createCallbackFcn(app, @PointDataButtonPushed, true);
            app.PointDataButton.Text = 'Point Data';
            app.XLabel = uilabel(app.DataSetsPanel);
            app.XLabel.Text = 'X';
            app.DisplayPanel = uipanel(app.DataSetsTab);
            app.DisplayPanel.Title = 'Display';
            app.DataSetstoDisplay = uidropdown(app.DisplayPanel);
            app.DataSetstoDisplay.Items = {'Select', 'Point Data'};
            app.DataSetstoDisplay.ValueChangedFcn = createCallbackFcn(app, @DataSetstoDisplayValueChanged, true);
            app.DataSetstoDisplay.Value = 'Select';
            app.PlotFilterEditFieldLabel = uilabel(app.DisplayPanel);
            app.PlotFilterEditFieldLabel.Text = 'Plot Filter';
            app.PlotDataSetsFilter = uieditfield(app.DisplayPanel, 'numeric');
            app.PlotDataSetsFilter.ValueChangedFcn = createCallbackFcn(app, @PlotDataSetsFilterValueChanged, true);
            app.PlotDataSets = uibutton(app.DisplayPanel, 'push');
            app.PlotDataSets.ButtonPushedFcn = createCallbackFcn(app, @PlotDataSetsButtonPushed, true);
            app.PlotDataSets.Text = 'Plot';
            app.RestartButton = uibutton(app.DataSetsTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed2, true);
            app.RestartButton.Text = 'Restart';
            app.CloseButton = uibutton(app.DataSetsTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.SpectralTab = uitab(app.TabGroup);
            app.SpectralTab.Title = 'Spectral';
            app.SpectralFeatureExtractionInputsPanel = uipanel(app.SpectralTab);
            app.SpectralFeatureExtractionInputsPanel.Title = 'Spectral Feature Extraction Inputs ';
            app.SpectralInputsMerge = uibutton(app.SpectralFeatureExtractionInputsPanel, 'push');
            app.SpectralInputsMerge.ButtonPushedFcn = createCallbackFcn(app, @SpectralInputsMergeButtonPushed, true);
            app.SpectralInputsMerge.Text = 'Merge';
            app.SpectralInputsPointD = uicheckbox(app.SpectralFeatureExtractionInputsPanel);
            app.SpectralInputsPointD.ValueChangedFcn = createCallbackFcn(app, @SpectralInputsPointDValueChanged, true);
            app.SpectralInputsPointD.Text = 'Point Data';
            app.ContinuousWaveletTransformCWTPanel = uipanel(app.SpectralTab);
            app.ContinuousWaveletTransformCWTPanel.Title = 'Continuous Wavelet Transform (CWT)';
            app.MotherWaveletDropDown = uidropdown(app.ContinuousWaveletTransformCWTPanel);
            app.MotherWaveletDropDown.Items = {'Select Mother Wavelet', 'Derivatives of Gaussian (Asymmetric)', 'Derivatives of Poisson (Asymmetric)'};
            app.MotherWaveletDropDown.ValueChangedFcn = createCallbackFcn(app, @MotherWaveletDropDownValueChanged, true);
            app.MotherWaveletDropDown.Value = 'Select Mother Wavelet';
            app.NumberofScalesnaLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.NumberofScalesnaLabel.Interpreter = 'latex';
            app.NumberofScalesnaLabel.Text = '$$N_a$$';
            app.N_aEditField = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.N_aEditField.AllowEmpty = 'on';
            app.N_aEditField.ValueChangedFcn = createCallbackFcn(app, @N_aEditFieldValueChanged, true);
            app.N_aEditField.Value = 1;
            app.ScaleDilationFactorLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.ScaleDilationFactorLabel.Interpreter = 'latex';
            app.ScaleDilationFactorLabel.Text = '$$δ$$';
            app.EditField = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.EditField.AllowEmpty = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.WSFRLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.WSFRLabel.Interpreter = 'latex';
            app.WSFRLabel.Text = '$$σ$$';
            app.EditField_3 = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.EditField_3.AllowEmpty = 'on';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 0.25;
            app.NumberofAnglesLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.NumberofAnglesLabel.Interpreter = 'latex';
            app.NumberofAnglesLabel.Text = '$$N_θ$$';
            app.Label_2 = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$$α$$';
            app.BetaSymEditField = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.BetaSymEditField.AllowEmpty = 'on';
            app.BetaSymEditField.ValueChangedFcn = createCallbackFcn(app, @BetaSymEditFieldValueChanged, true);
            app.BetaSymEditField.Value = 180;
            app.DerivativesofGussianPoissonWaveletsPanel = uipanel(app.ContinuousWaveletTransformCWTPanel);
            app.DerivativesofGussianPoissonWaveletsPanel.Title = 'Derivatives of Gussian / Poisson Wavelets';
            app.OrderXnEditFieldLabel = uilabel(app.DerivativesofGussianPoissonWaveletsPanel);
            app.OrderXnEditFieldLabel.Interpreter = 'latex';
            app.OrderXnEditFieldLabel.Text = '$$n$$';
            app.OrderXnEditField = uieditfield(app.DerivativesofGussianPoissonWaveletsPanel, 'numeric');
            app.OrderXnEditField.Value = 1;
            app.OrderYmEditFieldLabel = uilabel(app.DerivativesofGussianPoissonWaveletsPanel);
            app.OrderYmEditFieldLabel.Interpreter = 'latex';
            app.OrderYmEditFieldLabel.Text = '$$m$$';
            app.OrderYmEditField = uieditfield(app.DerivativesofGussianPoissonWaveletsPanel, 'numeric');
            app.aTextAreaLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.aTextAreaLabel.Interpreter = 'latex';
            app.aTextAreaLabel.Text = '$$(a)$$';
            app.aTextArea = uitextarea(app.ContinuousWaveletTransformCWTPanel);
            app.aTextArea.ValueChangedFcn = createCallbackFcn(app, @aTextAreaValueChanged, true);
            app.Label_3 = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '$$(θ)$$';
            app.TextArea = uitextarea(app.ContinuousWaveletTransformCWTPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.N_EditField = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.N_EditField.AllowEmpty = 'on';
            app.N_EditField.Value = 36;
            app.OrderXnEditFieldLabel_2 = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.OrderXnEditFieldLabel_2.Interpreter = 'latex';
            app.OrderXnEditFieldLabel_2.Text = '$$N_{CWT}$$';
            app.NumCWTFeatures = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.NumCWTFeatures.AllowEmpty = 'on';
            app.NumCWTFeatures.ValueChangedFcn = createCallbackFcn(app, @NumCWTFeaturesValueChanged, true);
            app.NumCWTFeatures.Editable = 'off';
            app.NumCWTFeatures.Value = [];
            app.CWTButton = uibutton(app.ContinuousWaveletTransformCWTPanel, 'push');
            app.CWTButton.ButtonPushedFcn = createCallbackFcn(app, @CWTButtonPushed, true);
            app.CWTButton.Text = 'CWT';
            app.ZLabel = uilabel(app.ContinuousWaveletTransformCWTPanel);
            app.ZLabel.Interpreter = 'latex';
            app.ZLabel.Text = '$$Z$$';
            app.PoissonUPwardContinuation = uieditfield(app.ContinuousWaveletTransformCWTPanel, 'numeric');
            app.PoissonUPwardContinuation.AllowEmpty = 'on';
            app.PoissonUPwardContinuation.Value = 1;
            app.SpectralFeatureSelectionPanel = uipanel(app.SpectralTab);
            app.SpectralFeatureSelectionPanel.Title = 'Spectral Feature Selection';
            app.FeatureSelectionButton = uibutton(app.SpectralFeatureSelectionPanel, 'push');
            app.FeatureSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureSelectionButtonPushed3, true);
            app.FeatureSelectionButton.Text = 'Feature Selection ';
            app.FeatureSaliencyLabel = uilabel(app.SpectralFeatureSelectionPanel);
            app.FeatureSaliencyLabel.Interpreter = 'latex';
            app.FeatureSaliencyLabel.Text = '$$λ$$';
            app.EditField_4 = uieditfield(app.SpectralFeatureSelectionPanel, 'numeric');
            app.EditField_4.AllowEmpty = 'on';
            app.EditField_4.Value = 95;
            app.EditField_5Label = uilabel(app.SpectralFeatureSelectionPanel);
            app.EditField_5Label.Interpreter = 'latex';
            app.EditField_5Label.Text = '$$γ$$';
            app.EditField_5 = uieditfield(app.SpectralFeatureSelectionPanel, 'numeric');
            app.EditField_5.AllowEmpty = 'on';
            app.EditField_5.Value = 0.95;
            app.OrderXnEditFieldLabel_3 = uilabel(app.SpectralFeatureSelectionPanel);
            app.OrderXnEditFieldLabel_3.Text = 'd';
            app.CWTReductionto = uieditfield(app.SpectralFeatureSelectionPanel, 'numeric');
            app.CWTReductionto.AllowEmpty = 'on';
            app.CWTReductionto.ValueChangedFcn = createCallbackFcn(app, @CWTReductiontoValueChanged, true);
            app.CWTReductionto.Value = 3;
            app.DisplayPanel_3 = uipanel(app.SpectralTab);
            app.DisplayPanel_3.Title = 'Display';
            app.SpectralDisplay = uidropdown(app.DisplayPanel_3);
            app.SpectralDisplay.Items = {'Select', 'CWT', 'Selected'};
            app.SpectralDisplay.ValueChangedFcn = createCallbackFcn(app, @SpectralDisplayValueChanged, true);
            app.SpectralDisplay.Value = 'Select';
            app.PlotFilterEditFieldLabel_3 = uilabel(app.DisplayPanel_3);
            app.PlotFilterEditFieldLabel_3.Text = 'Plot Filter';
            app.PlotSpectralFilter = uieditfield(app.DisplayPanel_3, 'numeric');
            app.PlotSpectralFilter.ValueChangedFcn = createCallbackFcn(app, @PlotSpectralFilterValueChanged, true);
            app.PlotSpectral = uibutton(app.DisplayPanel_3, 'push');
            app.PlotSpectral.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectralButtonPushed, true);
            app.PlotSpectral.Text = 'Plot';
            app.LineamentsTab = uitab(app.TabGroup);
            app.LineamentsTab.Title = 'Lineaments';
            app.uipanel14 = uipanel(app.LineamentsTab);
            app.uipanel14.Title = 'Digitized Lineaments';
            app.uipanel14.Tag = 'uipanel14';
            app.LineTargets_Spacing = uieditfield(app.uipanel14, 'text');
            app.LineTargets_Spacing.ValueChangedFcn = createCallbackFcn(app, @LineTargets_SpacingValueChanged, true);
            app.LineTargets_Spacing.Tag = 'edit72';
            app.LineTargets_Filter = uieditfield(app.uipanel14, 'text');
            app.LineTargets_Filter.ValueChangedFcn = createCallbackFcn(app, @LineTargets_FilterValueChanged, true);
            app.LineTargets_Filter.Tag = 'edit73';
            app.LineTargets_Filter.Value = '0';
            app.text98 = uilabel(app.uipanel14);
            app.text98.Tag = 'text98';
            app.text98.Text = 'Spacing';
            app.text99 = uilabel(app.uipanel14);
            app.text99.Tag = 'text99';
            app.text99.Text = 'Target Filter';
            app.text100 = uilabel(app.uipanel14);
            app.text100.Tag = 'text100';
            app.text100.Text = 'x';
            app.LineTargets_Cutoff_1 = uieditfield(app.uipanel14, 'text');
            app.LineTargets_Cutoff_1.ValueChangedFcn = createCallbackFcn(app, @LineTargets_Cutoff_1ValueChanged, true);
            app.LineTargets_Cutoff_1.Tag = 'edit89';
            app.text117 = uilabel(app.uipanel14);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Cut-off 1';
            app.LineTargets_Cutoff_2 = uieditfield(app.uipanel14, 'text');
            app.LineTargets_Cutoff_2.ValueChangedFcn = createCallbackFcn(app, @LineTargets_Cutoff_2ValueChanged, true);
            app.LineTargets_Cutoff_2.Tag = 'edit103';
            app.text132 = uilabel(app.uipanel14);
            app.text132.Tag = 'text132';
            app.text132.Text = 'Cut-off 2';
            app.ImageLoad_LineTarg = uibutton(app.uipanel14, 'push');
            app.ImageLoad_LineTarg.ButtonPushedFcn = createCallbackFcn(app, @ImageLoad_LineTargButtonPushed, true);
            app.ImageLoad_LineTarg.Text = 'Image';
            app.PointData_LineTarg = uibutton(app.uipanel14, 'push');
            app.PointData_LineTarg.ButtonPushedFcn = createCallbackFcn(app, @PointData_LineTargButtonPushed, true);
            app.PointData_LineTarg.Text = 'Point Data';
            app.X_nLabel = uilabel(app.uipanel14);
            app.X_nLabel.Tooltip = {''};
            app.X_nLabel.Interpreter = 'latex';
            app.X_nLabel.Text = '$$X_n$$';
            app.LineTargets_Xn = uieditfield(app.uipanel14, 'numeric');
            app.LineTargets_Xn.AllowEmpty = 'on';
            app.LineTargets_Xn.ValueChangedFcn = createCallbackFcn(app, @LineTargets_XnValueChanged, true);
            app.LineTargets_Xn.Editable = 'off';
            app.LineTargets_Xn.Value = [];
            app.Y_nLabel = uilabel(app.uipanel14);
            app.Y_nLabel.Interpreter = 'latex';
            app.Y_nLabel.Text = '$$Y_n$$';
            app.LineTargets_Yn = uieditfield(app.uipanel14, 'numeric');
            app.LineTargets_Yn.AllowEmpty = 'on';
            app.LineTargets_Yn.ValueChangedFcn = createCallbackFcn(app, @LineTargets_YnValueChanged, true);
            app.LineTargets_Yn.Editable = 'off';
            app.LineTargets_Yn.Value = [];
            app.Radius1 = uieditfield(app.uipanel14, 'text');
            app.Radius1.InputType = 'digits';
            app.Radius1.Value = '10';
            app.Radius2 = uieditfield(app.uipanel14, 'text');
            app.Radius2.InputType = 'digits';
            app.Radius2.Value = '11';
            app.text98_3 = uilabel(app.uipanel14);
            app.text98_3.Interpreter = 'latex';
            app.text98_3.Text = '$$r_1$$';
            app.text99_3 = uilabel(app.uipanel14);
            app.text99_3.Interpreter = 'latex';
            app.text99_3.Text = '$$r_2$$';
            app.Beta_F = uieditfield(app.uipanel14, 'text');
            app.Beta_F.Value = '0.2';
            app.text99_4 = uilabel(app.uipanel14);
            app.text99_4.Interpreter = 'latex';
            app.text99_4.Text = '$$β$$';
            app.uipanel19 = uipanel(app.LineamentsTab);
            app.uipanel19.Title = 'Lineament Extraction';
            app.uipanel19.Tag = 'uipanel19';
            app.text119 = uilabel(app.uipanel19);
            app.text119.Tag = 'text119';
            app.text119.Interpreter = 'latex';
            app.text119.Text = '$$w$$';
            app.SFW = uieditfield(app.uipanel19, 'text');
            app.SFW.ValueChangedFcn = createCallbackFcn(app, @SFWValueChanged, true);
            app.SFW.Tag = 'edit91';
            app.SFW.Tooltip = {'Step filtering width.'};
            app.Lineaments_Manual = uibutton(app.uipanel19, 'push');
            app.Lineaments_Manual.ButtonPushedFcn = createCallbackFcn(app, @Lineaments_ManualButtonPushed, true);
            app.Lineaments_Manual.Tag = 'Lineaments';
            app.Lineaments_Manual.Text = 'Lineament Extraction';
            app.text127 = uilabel(app.uipanel19);
            app.text127.Tag = 'text127';
            app.text127.Interpreter = 'latex';
            app.text127.Text = '$$υ$$';
            app.VSFW = uieditfield(app.uipanel19, 'text');
            app.VSFW.ValueChangedFcn = createCallbackFcn(app, @VSFWValueChanged, true);
            app.VSFW.Tag = 'edit98';
            app.VSFW.Tooltip = {'Variability of step filtering width.'};
            app.VSFW.Value = '0.25';
            app.text128 = uilabel(app.uipanel19);
            app.text128.Tag = 'text128';
            app.text128.Interpreter = 'latex';
            app.text128.Text = '$$R$$';
            app.LineRes = uieditfield(app.uipanel19, 'text');
            app.LineRes.ValueChangedFcn = createCallbackFcn(app, @LineResValueChanged, true);
            app.LineRes.Tag = 'edit99';
            app.LineRes.Tooltip = {'Resolution factor: A value more than 1 increases and less than 1 decreases resolution.'};
            app.LineRes.Value = '1';
            app.text130 = uilabel(app.uipanel19);
            app.text130.Tag = 'text130';
            app.text130.Interpreter = 'latex';
            app.text130.Text = '$$N_ϕ$$';
            app.LineAng = uieditfield(app.uipanel19, 'text');
            app.LineAng.ValueChangedFcn = createCallbackFcn(app, @LineAngValueChanged, true);
            app.LineAng.Tag = 'edit101';
            app.LineAng.Tooltip = {'Number of step filtering angles.'};
            app.LineAng.Value = '36';
            app.text131_2 = uilabel(app.uipanel19);
            app.text131_2.Tag = 'text131';
            app.text131_2.Interpreter = 'latex';
            app.text131_2.Text = '$$σ_I$$';
            app.LineImFilterR = uieditfield(app.uipanel19, 'text');
            app.LineImFilterR.ValueChangedFcn = createCallbackFcn(app, @LineImFilterRValueChanged, true);
            app.LineImFilterR.Tag = 'edit102';
            app.LineImFilterR.Tooltip = {'Image filter ratio.'};
            app.LineImFilterR.Value = '0';
            app.LineSpectralInputsPointD = uicheckbox(app.uipanel19);
            app.LineSpectralInputsPointD.ValueChangedFcn = createCallbackFcn(app, @LineSpectralInputsPointDValueChanged, true);
            app.LineSpectralInputsPointD.Text = 'P';
            app.LineSpectralInputsSPCA = uicheckbox(app.uipanel19);
            app.LineSpectralInputsSPCA.ValueChangedFcn = createCallbackFcn(app, @LineSpectralInputsSPCAValueChanged, true);
            app.LineSpectralInputsSPCA.Text = 'CWT Selected';
            app.LineSpectralInputsMerge = uibutton(app.uipanel19, 'push');
            app.LineSpectralInputsMerge.ButtonPushedFcn = createCallbackFcn(app, @LineSpectralInputsMergeButtonPushed, true);
            app.LineSpectralInputsMerge.Text = 'Merge';
            app.text130_5 = uilabel(app.uipanel19);
            app.text130_5.Interpreter = 'latex';
            app.text130_5.Text = '$$ξ$$';
            app.SLC_Manual = uieditfield(app.uipanel19, 'text');
            app.SLC_Manual.Tooltip = {'Hysteresis threshold (%): Percentage of Strong Lineamnet Components (SLC).  '};
            app.SLC_Manual.Value = '50';
            app.XLabel_2 = uilabel(app.uipanel19);
            app.XLabel_2.Text = 'X';
            app.XnLabel_2 = uilabel(app.uipanel19);
            app.XnLabel_2.Interpreter = 'latex';
            app.XnLabel_2.Text = '$$X_n$$';
            app.Xn_Manual = uieditfield(app.uipanel19, 'numeric');
            app.Xn_Manual.AllowEmpty = 'on';
            app.Xn_Manual.Editable = 'off';
            app.Xn_Manual.Tooltip = {'Number of pixels in x-direction'};
            app.Xn_Manual.Value = [];
            app.YnLabel_2 = uilabel(app.uipanel19);
            app.YnLabel_2.Interpreter = 'latex';
            app.YnLabel_2.Text = '$$Y_n$$';
            app.Yn_Manual = uieditfield(app.uipanel19, 'numeric');
            app.Yn_Manual.AllowEmpty = 'on';
            app.Yn_Manual.Editable = 'off';
            app.Yn_Manual.Tooltip = {'Number of pixels in y-direction'};
            app.Yn_Manual.Value = [];
            app.DisplayPanel_4 = uipanel(app.LineamentsTab);
            app.DisplayPanel_4.Title = 'Display';
            app.LineDisplay = uidropdown(app.DisplayPanel_4);
            app.LineDisplay.Items = {'Plot Results', 'Target Lines', 'Rose Diagram (Target)', 'All Lineaments (Manual)', 'Curvilinearity Control (Manual)', 'Line Densities (Manual)', 'Lineaments (Manual)', 'Deep Lineaments (Manual)', 'Shallow Lineaments (Manual)', 'Rose Diagram (Manual)', 'Fbeta Score (Manual)', 'CWT (Auto)', 'Selected (Auto)', 'All Lineaments (Auto)', 'Curvilinearity Control (Auto)', 'Line Densities (Auto)', 'Lineaments (Auto)', 'Deep Lineaments (Auto)', 'Shallow Lineaments (Auto)', 'Rose Diagram (Auto)', 'Fbeta Score (Auto)', 'BHO Progress (Auto)', 'BHO Performance (Auto)', 'Feature Correlation Matrix (Auto)', 'Importance (Auto)'};
            app.LineDisplay.ValueChangedFcn = createCallbackFcn(app, @LineDisplayValueChanged, true);
            app.LineDisplay.Value = 'Plot Results';
            app.PlotFilterEditFieldLabel_5 = uilabel(app.DisplayPanel_4);
            app.PlotFilterEditFieldLabel_5.Text = 'Plot Filter';
            app.LinePlotFilter = uieditfield(app.DisplayPanel_4, 'numeric');
            app.LinePlotFilter.ValueChangedFcn = createCallbackFcn(app, @LinePlotFilterValueChanged, true);
            app.LinePlot = uibutton(app.DisplayPanel_4, 'push');
            app.LinePlot.ButtonPushedFcn = createCallbackFcn(app, @LinePlotButtonPushed, true);
            app.LinePlot.Text = 'Plot';
            app.uipanel19_2 = uipanel(app.LineamentsTab);
            app.uipanel19_2.Title = 'Lineament Extraction by Bayesian Topology Optimization (BTO)';
            app.text119_2 = uilabel(app.uipanel19_2);
            app.text119_2.Tooltip = {''};
            app.text119_2.Interpreter = 'latex';
            app.text119_2.Text = '$$ \bar{w}^* $$';
            app.SFW_Auto = uieditfield(app.uipanel19_2, 'text');
            app.SFW_Auto.Tooltip = {'Step filtering width'};
            app.text127_2 = uilabel(app.uipanel19_2);
            app.text127_2.Tooltip = {''};
            app.text127_2.Interpreter = 'latex';
            app.text127_2.Text = '$$υ^*$$';
            app.VSFW_Auto = uieditfield(app.uipanel19_2, 'text');
            app.VSFW_Auto.Editable = 'off';
            app.VSFW_Auto.Tooltip = {'Variability of step filtering width.'};
            app.text128_2 = uilabel(app.uipanel19_2);
            app.text128_2.Tooltip = {''};
            app.text128_2.Interpreter = 'latex';
            app.text128_2.Text = '$$R$$';
            app.LineRes_Auto = uieditfield(app.uipanel19_2, 'text');
            app.LineRes_Auto.Tooltip = {'Resolution factor: A value more than 1 increases and less than 1 decreases resolution.'};
            app.LineRes_Auto.Value = '1';
            app.text130_2 = uilabel(app.uipanel19_2);
            app.text130_2.Tooltip = {''};
            app.text130_2.Interpreter = 'latex';
            app.text130_2.Text = '$$N_ϕ^*$$';
            app.LineAng_Auto = uieditfield(app.uipanel19_2, 'text');
            app.LineAng_Auto.Editable = 'off';
            app.LineAng_Auto.Tooltip = {'Number of step filtering angles.'};
            app.text131_4 = uilabel(app.uipanel19_2);
            app.text131_4.Tooltip = {''};
            app.text131_4.Interpreter = 'latex';
            app.text131_4.Text = '$$σ_I$$';
            app.LineImFilterR_Auto = uieditfield(app.uipanel19_2, 'text');
            app.LineImFilterR_Auto.Tooltip = {'Image filter ratio.'};
            app.LineImFilterR_Auto.Value = '0';
            app.text119_3 = uilabel(app.uipanel19_2);
            app.text119_3.Interpreter = 'latex';
            app.text119_3.Text = '$$N_a$$';
            app.NumberofScales_Auto = uieditfield(app.uipanel19_2, 'text');
            app.NumberofScales_Auto.InputType = 'digits';
            app.NumberofScales_Auto.Tooltip = {'Number of kCWT scales'};
            app.NumberofScales_Auto.Value = '1';
            app.text127_3 = uilabel(app.uipanel19_2);
            app.text127_3.Interpreter = 'latex';
            app.text127_3.Text = '$$δ$$';
            app.ScaleDilationFactor_Auto = uieditfield(app.uipanel19_2, 'text');
            app.ScaleDilationFactor_Auto.Tooltip = {'kCWT scale dilation factor.'};
            app.ScaleDilationFactor_Auto.Value = '1';
            app.text128_3 = uilabel(app.uipanel19_2);
            app.text128_3.Tooltip = {''};
            app.text128_3.Interpreter = 'latex';
            app.text128_3.Text = '$$N_{CWT}$$';
            app.NumCWTFeatures_Auto = uieditfield(app.uipanel19_2, 'text');
            app.NumCWTFeatures_Auto.Editable = 'off';
            app.NumCWTFeatures_Auto.Tooltip = {'Number of extracted kCWT features'};
            app.text131_5 = uilabel(app.uipanel19_2);
            app.text131_5.Tooltip = {''};
            app.text131_5.Interpreter = 'latex';
            app.text131_5.Text = '$$N_θ^*$$';
            app.NumberofAngles_Auto = uieditfield(app.uipanel19_2, 'text');
            app.NumberofAngles_Auto.Editable = 'off';
            app.NumberofAngles_Auto.Tooltip = {'Number of kCWT angles'};
            app.text131_6 = uilabel(app.uipanel19_2);
            app.text131_6.Tooltip = {''};
            app.text131_6.Interpreter = 'latex';
            app.text131_6.Text = '$$σ^*$$';
            app.WSFR_Auto = uieditfield(app.uipanel19_2, 'text');
            app.WSFR_Auto.Editable = 'off';
            app.WSFR_Auto.Tooltip = {'kCWT filtering ratio'};
            app.Lineaments_Auto_2 = uibutton(app.uipanel19_2, 'push');
            app.Lineaments_Auto_2.ButtonPushedFcn = createCallbackFcn(app, @Lineaments_Auto_2ButtonPushed, true);
            app.Lineaments_Auto_2.Tooltip = {'Automatic lineament extraction with Bayesian topology optimization.'};
            app.Lineaments_Auto_2.Text = 'Lineament Extraction (BTO)';
            app.text131 = uilabel(app.uipanel19_2);
            app.text131.Tag = 'text131';
            app.text131.Tooltip = {''};
            app.text131.Text = 'BTO Max Iterations';
            app.BHOIter = uieditfield(app.uipanel19_2, 'text');
            app.BHOIter.ValueChangedFcn = createCallbackFcn(app, @BHOIterValueChanged, true);
            app.BHOIter.Tag = 'edit102';
            app.BHOIter.Tooltip = {'Maximum iterations for Bayesian topology optimization.'};
            app.BHOIter.Value = '15';
            app.text130_3 = uilabel(app.uipanel19_2);
            app.text130_3.Tooltip = {''};
            app.text130_3.Interpreter = 'latex';
            app.text130_3.Text = '$$d$$';
            app.CWTReductionto_Auto = uieditfield(app.uipanel19_2, 'text');
            app.CWTReductionto_Auto.Tooltip = {'Number of selected features after dimensionality reduction.'};
            app.CWTReductionto_Auto.Value = '3';
            app.MotherWaveletDropDown_Auto = uidropdown(app.uipanel19_2);
            app.MotherWaveletDropDown_Auto.Items = {'Select Mother Wavelet', 'Derivatives of Poisson (Asymmetric)'};
            app.MotherWaveletDropDown_Auto.ValueChangedFcn = createCallbackFcn(app, @MotherWaveletDropDown_AutoValueChanged, true);
            app.MotherWaveletDropDown_Auto.Value = 'Select Mother Wavelet';
            app.SpectralInputsPointD_Auto = uicheckbox(app.uipanel19_2);
            app.SpectralInputsPointD_Auto.Text = 'P';
            app.SpectralInputsMerge_Auto = uibutton(app.uipanel19_2, 'push');
            app.SpectralInputsMerge_Auto.ButtonPushedFcn = createCallbackFcn(app, @SpectralInputsMerge_AutoButtonPushed, true);
            app.SpectralInputsMerge_Auto.Text = 'Merge';
            app.text130_4 = uilabel(app.uipanel19_2);
            app.text130_4.Tooltip = {''};
            app.text130_4.Interpreter = 'latex';
            app.text130_4.Text = '$$ξ^*$$';
            app.SLC_Auto = uieditfield(app.uipanel19_2, 'text');
            app.SLC_Auto.Editable = 'off';
            app.SLC_Auto.Tooltip = {'Hysteresis threshold (%): Percentage of Strong Lineamnet Components (SLC).  '};
            app.Label = uilabel(app.uipanel19_2);
            app.Label.Tooltip = {''};
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$$λ^*$$';
            app.FeatureSaliency_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.FeatureSaliency_Auto.AllowEmpty = 'on';
            app.FeatureSaliency_Auto.Editable = 'off';
            app.FeatureSaliency_Auto.Tooltip = {'Saliency (%)'};
            app.FeatureSaliency_Auto.Value = [];
            app.DissimilarityEditFieldLabel_2 = uilabel(app.uipanel19_2);
            app.DissimilarityEditFieldLabel_2.Tooltip = {''};
            app.DissimilarityEditFieldLabel_2.Interpreter = 'latex';
            app.DissimilarityEditFieldLabel_2.Text = '$$γ^*$$';
            app.Dissimilarity_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.Dissimilarity_Auto.AllowEmpty = 'on';
            app.Dissimilarity_Auto.Editable = 'off';
            app.Dissimilarity_Auto.Tooltip = {'Dissimilarity'};
            app.Dissimilarity_Auto.Value = [];
            app.OrderXnEditFieldLabel_4 = uilabel(app.uipanel19_2);
            app.OrderXnEditFieldLabel_4.Tooltip = {''};
            app.OrderXnEditFieldLabel_4.Interpreter = 'latex';
            app.OrderXnEditFieldLabel_4.Text = '$$n^*$$';
            app.OrderXn_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.OrderXn_Auto.AllowEmpty = 'on';
            app.OrderXn_Auto.Editable = 'off';
            app.OrderXn_Auto.Tooltip = {'Poisson derivative order in x-direction'};
            app.OrderXn_Auto.Value = [];
            app.OrderYmEditFieldLabel_2 = uilabel(app.uipanel19_2);
            app.OrderYmEditFieldLabel_2.Tooltip = {''};
            app.OrderYmEditFieldLabel_2.Interpreter = 'latex';
            app.OrderYmEditFieldLabel_2.Text = '$$m^*$$';
            app.OrderYm_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.OrderYm_Auto.AllowEmpty = 'on';
            app.OrderYm_Auto.Editable = 'off';
            app.OrderYm_Auto.Tooltip = {'Poisson derivative order in y-direction'};
            app.OrderYm_Auto.Value = [];
            app.text128_4 = uilabel(app.uipanel19_2);
            app.text128_4.Tooltip = {''};
            app.text128_4.Interpreter = 'latex';
            app.text128_4.Text = '$$Z^*$$';
            app.PoissonUPwardContinuation_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.PoissonUPwardContinuation_Auto.AllowEmpty = 'on';
            app.PoissonUPwardContinuation_Auto.Editable = 'off';
            app.PoissonUPwardContinuation_Auto.Tooltip = {'Poisson kernel upward continuation'};
            app.PoissonUPwardContinuation_Auto.Value = [];
            app.XLabel_3 = uilabel(app.uipanel19_2);
            app.XLabel_3.Text = 'X';
            app.XnLabel_3 = uilabel(app.uipanel19_2);
            app.XnLabel_3.Interpreter = 'latex';
            app.XnLabel_3.Text = '$$X_n$$';
            app.Xn_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.Xn_Auto.AllowEmpty = 'on';
            app.Xn_Auto.Editable = 'off';
            app.Xn_Auto.Tooltip = {'Number of pixels in x-direction'};
            app.Xn_Auto.Value = [];
            app.YnLabel_3 = uilabel(app.uipanel19_2);
            app.YnLabel_3.Interpreter = 'latex';
            app.YnLabel_3.Text = '$$Y_n$$';
            app.Yn_Auto = uieditfield(app.uipanel19_2, 'numeric');
            app.Yn_Auto.AllowEmpty = 'on';
            app.Yn_Auto.Editable = 'off';
            app.Yn_Auto.Tooltip = {'Number of pixels in y-direction'};
            app.Yn_Auto.Value = [];
            app.FixedParametersLabel = uilabel(app.uipanel19_2);
            app.FixedParametersLabel.Text = 'Fixed Parameters';
            app.HyperparametersLabel = uilabel(app.uipanel19_2);
            app.HyperparametersLabel.Text = 'Hyperparameters';
            app.RangeLabel = uilabel(app.uipanel19_2);
            app.RangeLabel.Text = 'Range';
            app.w_range = uieditfield(app.uipanel19_2, 'text');
            app.w_range.Tooltip = {'Factors determine the minimum and maximum of the initial w. For example, when w = 100, the Bayesian optimization will fine-tune the w between 75 and 250. '};
            app.w_range.Value = '0.75,2.5';
            app.CenterLabel = uilabel(app.uipanel19_2);
            app.CenterLabel.Text = 'Center';
            app.w_center = uieditfield(app.uipanel19_2, 'text');
            app.w_center.Tooltip = {'Initial step filtering width: The Bayesian optimization modulates around this central value.'};
            app.VSFW_range = uieditfield(app.uipanel19_2, 'text');
            app.VSFW_range.Value = '0,1';
            app.StepFAngles_range = uieditfield(app.uipanel19_2, 'text');
            app.StepFAngles_range.Value = '12,72';
            app.HystT_range = uieditfield(app.uipanel19_2, 'text');
            app.HystT_range.Value = '35,75';
            app.Dissimilarity_range = uieditfield(app.uipanel19_2, 'text');
            app.Dissimilarity_range.Tooltip = {''};
            app.Dissimilarity_range.Value = '0,1';
            app.Saliency_range = uieditfield(app.uipanel19_2, 'text');
            app.Saliency_range.Value = '0,100';
            app.CWTAngles__range = uieditfield(app.uipanel19_2, 'text');
            app.CWTAngles__range.Value = '12,72';
            app.Z_range = uieditfield(app.uipanel19_2, 'text');
            app.Z_range.Value = '1,5';
            app.sigma_range = uieditfield(app.uipanel19_2, 'text');
            app.sigma_range.Value = '0,1';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportButtonGroup = uibuttongroup(app.ExportTab);
            app.ExportButtonGroup.Title = 'Export';
            app.SelectedManualButton = uiradiobutton(app.ExportButtonGroup);
            app.SelectedManualButton.Text = 'Selected (Manual)';
            app.SelectedManualButton.Value = true;
            app.PDataButton = uiradiobutton(app.ExportButtonGroup);
            app.PDataButton.Text = 'P Data';
            app.Export_CSV_2 = uibutton(app.ExportButtonGroup, 'push');
            app.Export_CSV_2.ButtonPushedFcn = createCallbackFcn(app, @Export_CSV_2ButtonPushed, true);
            app.Export_CSV_2.Text = 'Export to CSV';
            app.Export_GRD_2 = uibutton(app.ExportButtonGroup, 'push');
            app.Export_GRD_2.ButtonPushedFcn = createCallbackFcn(app, @Export_GRD_2ButtonPushed, true);
            app.Export_GRD_2.Text = 'Export to GeoTIFF / Shapefile ';
            app.EditField_2Label_2 = uilabel(app.ExportButtonGroup);
            app.EditField_2Label_2.Text = '#';
            app.ExportNum = uieditfield(app.ExportButtonGroup, 'numeric');
            app.ExportNum.AllowEmpty = 'on';
            app.ExportNum.Value = 1;
            app.CWTManualButton = uiradiobutton(app.ExportButtonGroup);
            app.CWTManualButton.Text = 'CWT (Manual)';
            app.DensitiesManualButton = uiradiobutton(app.ExportButtonGroup);
            app.DensitiesManualButton.Text = 'Densities (Manual)';
            app.AllLinesManualButton = uiradiobutton(app.ExportButtonGroup);
            app.AllLinesManualButton.Text = 'All Lines (Manual)';
            app.DeepLinesManualButton = uiradiobutton(app.ExportButtonGroup);
            app.DeepLinesManualButton.Text = 'Deep Lines (Manual)';
            app.ShallowLinesManualButton = uiradiobutton(app.ExportButtonGroup);
            app.ShallowLinesManualButton.Text = 'Shallow Lines (Manual)';
            app.DensitiesAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.DensitiesAutoButton.Text = 'Densities (Auto)';
            app.AllLinesAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.AllLinesAutoButton.Text = 'All Lines (Auto)';
            app.DeepLinesAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.DeepLinesAutoButton.Text = 'Deep Lines (Auto)';
            app.ShallowLinesAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.ShallowLinesAutoButton.Text = 'Shallow Lines (Auto)';
            app.SelectedAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.SelectedAutoButton.Text = 'Selected (Auto)';
            app.CWTAutoButton = uiradiobutton(app.ExportButtonGroup);
            app.CWTAutoButton.Text = 'CWT (Auto)';
            % Show the figure after all components are created
            app.BWTO2Dver10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BWTO2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BWTO2Dver10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BWTO2Dver10UIFigure)
        end
    end
end